classdef Save_data_win < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ReloadDefaultsButton           matlab.ui.control.Button
        ContactWireLabel               matlab.ui.control.Label
        ELREditField                   matlab.ui.control.EditField
        ELREditFieldLabel              matlab.ui.control.Label
        SiteNameEditField              matlab.ui.control.EditField
        SiteNameEditFieldLabel         matlab.ui.control.Label
        ContactWireTypeDropDown        matlab.ui.control.DropDown
        ContactWireTypeDropDownLabel   matlab.ui.control.Label
        SavewithoriginalvaluesButton   matlab.ui.control.Button
        ReloadOriginalsButton          matlab.ui.control.Button
        FeatureDistanceEditField       matlab.ui.control.EditField
        FeatureDistanceEditFieldLabel  matlab.ui.control.Label
        TrackIDEditField               matlab.ui.control.EditField
        TrackIDEditFieldLabel          matlab.ui.control.Label
        MilageEditField                matlab.ui.control.EditField
        MilageEditFieldLabel           matlab.ui.control.Label
        SentinalEditField              matlab.ui.control.EditField
        SentinalEditFieldLabel         matlab.ui.control.Label
        OperatorEditField              matlab.ui.control.EditField
        OperatorEditFieldLabel         matlab.ui.control.Label
        TowerIDEditField               matlab.ui.control.EditField
        TowerIDEditFieldLabel          matlab.ui.control.Label
        NotesEditField                 matlab.ui.control.EditField
        NotesEditFieldLabel            matlab.ui.control.Label
        SavewithchangedvaluesButton    matlab.ui.control.Button
        AdjustOperatingSettingsandSaveLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        parent_app                 ;   
        default_options            ;   
        contact_wire_options        = {'120','107'};
        operator_settings          ;
        operator_settings_new      ; 
                

   %    operator_settings_table    ;


    end
    
    methods (Access = private)
        
        function worked_ = set_edit_box_values(app,struct_)

            worked_ = 1; 
            %try
            app.OperatorEditField.Value        = struct_.Name;     
            app.SentinalEditField.Value        = num2str(struct_.Sentinel_No);
            app.NotesEditField.Value           = struct_.Notes;
            app.TowerIDEditField.Value         = struct_.Tower_ID;
            app.SiteNameEditField.Value        = struct_.Site_name;
            app.ELREditField.Value             = num2str(struct_.ELR);
            app.MilageEditField.Value          = num2str(struct_.Milage);
            app.TrackIDEditField.Value         = num2str(struct_.Track_ID);
            app.FeatureDistanceEditField.Value = num2str(struct_.feature_distance_m);   
            app.ContactWireLabel.Text          = app.contact_wire_options{struct_.contact_wire_type_120_107};
            app.ContactWireTypeDropDown.Value  = app.contact_wire_options{struct_.contact_wire_type_120_107};
                        
            %catch
            %worked_ = 0;
            %end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, par_, struct_1, struct_2)
                        
            app.parent_app = par_;
            app.operator_settings = struct_1;
            app.default_options = struct_2;
            app.operator_settings_new = app.operator_settings; 
            app.ContactWireTypeDropDown.Items = app.contact_wire_options; 

            worked_ = set_edit_box_values(app,app.operator_settings);

            if worked_ == 0 
            msgbox('warning :  problem with assigningvalues to editboxes');
            end
          
            % read in the operator settings and the default settings
            % Populate the  edit boxes with the correct values 
            % Set the status of the buttons  ( need to create the function ) 


        end

        % Value changed function: OperatorEditField
        function OperatorEditFieldValueChanged(app, event)
            value = app.OperatorEditField.Value;

            [messge_ , check_ok] = Check_Operator_settings('Name',value);

            if    check_ok ==1
            app.operator_settings_new.Name = value ;     
            else
            msgbox(['Warning: ',messge_])
            app.OperatorEditField.Value = app.operator_settings_new.Name; 
            end % if    check_ok ==1                

        end

        % Value changed function: SentinalEditField
        function SentinalEditFieldValueChanged(app, event)
            value = app.SentinalEditField.Value;
 
            
            if ~isnan(str2double(value))
            [messge_ , check_ok] = Check_Operator_settings('Sentinel_No',str2double(value));
            else
            messge_ ='must be a number)';    
            check_ok = 0;    
            end


            if    check_ok ==1
            app.operator_settings_new.Sentinel_No = str2num(value);     
            else
            msgbox(['Warning: ',messge_])    
            app.SentinalEditField.Value = num2str(app.operator_settings_new.Sentinel_No); 
            end % if    check_ok ==1                

        end

        % Value changed function: NotesEditField
        function NotesEditFieldValueChanged(app, event)
            value = app.NotesEditField.Value;
           
            [messge_ , check_ok] = Check_Operator_settings('Notes',value);
            
            if    check_ok ==1
            app.operator_settings_new.Notes = value ;     
            else
            msgbox(['Warning: ',messge_])    
            app.NotesEditField.Value  =  app.operator_settings_new.Notes;    
            end % if    check_ok ==1                

        end

        % Value changed function: TowerIDEditField
        function TowerIDEditFieldValueChanged(app, event)
            value = app.TowerIDEditField.Value;
            
            [messge_ , check_ok] = Check_Operator_settings('Tower_ID',value);
            
            if    check_ok ==1
            app.operator_settings_new.Tower_ID = value;     
            else
            msgbox(['Warning: ',messge_])    
            app.TowerIDEditField.Value =  app.operator_settings_new.Tower_ID; 
            end % if    check_ok ==1           

        end

        % Value changed function: SiteNameEditField
        function SiteNameEditFieldValueChanged(app, event)
            value = app.SiteNameEditField.Value;
            
            [messge_ , check_ok] = Check_Operator_settings('Site_name',value);
            
            if    check_ok ==1
            app.operator_settings_new.Site_name = value;     
            else
            msgbox(['Warning: ',messge_])    
            app.SiteNameEditField.Value =  app.operator_settings_new.Site_name; 
            end % if    check_ok ==1           

        end

        % Value changed function: ELREditField
        function ELREditFieldValueChanged(app, event)
            value = app.ELREditField.Value;
            
            
            if ~isnan(str2double(value))
            [messge_ , check_ok] = Check_Operator_settings('ELR',value);
                     
            else
            messge_ ='must be a number)';
            check_ok = 0;    
            end


            if    check_ok ==1
            app.operator_settings_new.ELR = value;     
            else
            msgbox(['Warning: ',messge_])    
            app.ELREditField.Value =  num2str(app.operator_settings_new.ELR); 
            end % if    check_ok ==1           

        end

        % Value changed function: MilageEditField
        function MilageEditFieldValueChanged(app, event)
            value = app.MilageEditField.Value;
            
            if ~isnan(str2double(value))
            [messge_ , check_ok] = Check_Operator_settings('Milage', str2double(value));
           
           
            else
            messge_ ='must be a number)';  
            check_ok = 0;    
            end


            if    check_ok ==1
            app.operator_settings_new.Milage = str2num(value);     
            else
            msgbox(['Warning: ',messge_])    
            app.MilageEditField.Value = num2str(app.operator_settings_new.Milage); 
 
            end % if    check_ok ==1         


        end

        % Value changed function: TrackIDEditField
        function TrackIDEditFieldValueChanged(app, event)
            value = app.TrackIDEditField.Value;
            

            if ~isnan(str2double(value))
            [messge_ , check_ok] = Check_Operator_settings('Track_ID',str2double(value));
           
            else
            messge_ ='must be a number)';   
            check_ok = 0;    
            end


            if    check_ok ==1
            app.operator_settings_new.Track_ID = str2num(value);     
            else
            msgbox(['Warning: ',messge_])    
            app.TrackIDEditField.Value = num2str(app.operator_settings_new.Track_ID); 
            end
        end

        % Value changed function: FeatureDistanceEditField
        function FeatureDistanceEditFieldValueChanged(app, event)
            value = app.FeatureDistanceEditField.Value;
            
            if ~isnan(str2double(value))
            [messge_ , check_ok] = Check_Operator_settings('feature_distance_m', str2double(value));
            else
            messge_ ='must be a number)';   
            check_ok = 0;    
            end


            if    check_ok ==1
            app.operator_settings_new.feature_distance_m = str2double(value);     
            else
            msgbox(['Warning: ',messge_])    
            app.FeatureDistanceEditField.Value = num2str(app.operator_settings_new.feature_distance_m) ;
    
            end
        end

        % Value changed function: ContactWireTypeDropDown
        function ContactWireTypeDropDownValueChanged(app, event)
            value = app.ContactWireTypeDropDown.Value;
            
            %set the    

            app.ContactWireLabel.Text =  value;

            switch(value)
                case('120')
               app.operator_settings_new.contact_wire_type_120_107 = 1;
                case('107')
               app.operator_settings_new.contact_wire_type_120_107  = 2;  
            end

        end

        % Button pushed function: ReloadOriginalsButton
        function ReloadOriginalsButtonPushed(app, event)
            
        app.operator_settings_new = app.operator_settings;
        worked_ = set_edit_box_values(app,app.operator_settings);

        if worked_ == 0 
        msgbox('warning :  problem with assigningvalues to editboxes');
        end

        end

        % Button pushed function: SavewithoriginalvaluesButton
        function SavewithoriginalvaluesButtonPushed(app, event)
            % app.operator_settings

             set_operatorsettings_simple(app.parent_app, app.operator_settings)
             delete(app)
            
       
        end

        % Button pushed function: SavewithchangedvaluesButton
        function SavewithchangedvaluesButtonPushed(app, event)
            % app.operator_settings_new
             set_operatorsettings_simple(app.parent_app, app.operator_settings_new)
             delete(app) 

        end

        % Button pushed function: ReloadDefaultsButton
        function ReloadDefaultsButtonPushed(app, event)
            [app.operator_settings,ok_] = load_structure_from_file([pwd,app.default_options.Operator_Settings_fname],....
            app.default_options.Operator_expected_fields);

            app.operator_settings_new = app.operator_settings ;  
           
            if ok_~= 1 ; msgbox('Warning:: structure fields dont match defaults') ;end      
            worked_ = set_edit_box_values(app,app.operator_settings);

            if worked_ == 0 
            msgbox('warning :  problem with assigningvalues to editboxes');
            end %if worked_ == 0
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AdjustOperatingSettingsandSaveLabel = uilabel(app.UIFigure);
            app.AdjustOperatingSettingsandSaveLabel.Text = 'Adjust Operating Settings and Save ';
            app.SavewithchangedvaluesButton = uibutton(app.UIFigure, 'push');
            app.SavewithchangedvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @SavewithchangedvaluesButtonPushed, true);
            app.SavewithchangedvaluesButton.Text = 'Save with changed values';
            app.NotesEditFieldLabel = uilabel(app.UIFigure);
            app.NotesEditFieldLabel.Text = 'Notes';
            app.NotesEditField = uieditfield(app.UIFigure, 'text');
            app.NotesEditField.ValueChangedFcn = createCallbackFcn(app, @NotesEditFieldValueChanged, true);
            app.TowerIDEditFieldLabel = uilabel(app.UIFigure);
            app.TowerIDEditFieldLabel.Text = 'Tower ID';
            app.TowerIDEditField = uieditfield(app.UIFigure, 'text');
            app.TowerIDEditField.ValueChangedFcn = createCallbackFcn(app, @TowerIDEditFieldValueChanged, true);
            app.OperatorEditFieldLabel = uilabel(app.UIFigure);
            app.OperatorEditFieldLabel.Text = 'Operator';
            app.OperatorEditField = uieditfield(app.UIFigure, 'text');
            app.OperatorEditField.ValueChangedFcn = createCallbackFcn(app, @OperatorEditFieldValueChanged, true);
            app.SentinalEditFieldLabel = uilabel(app.UIFigure);
            app.SentinalEditFieldLabel.Text = 'Sentinal';
            app.SentinalEditField = uieditfield(app.UIFigure, 'text');
            app.SentinalEditField.ValueChangedFcn = createCallbackFcn(app, @SentinalEditFieldValueChanged, true);
            app.MilageEditFieldLabel = uilabel(app.UIFigure);
            app.MilageEditFieldLabel.Text = 'Milage';
            app.MilageEditField = uieditfield(app.UIFigure, 'text');
            app.MilageEditField.ValueChangedFcn = createCallbackFcn(app, @MilageEditFieldValueChanged, true);
            app.TrackIDEditFieldLabel = uilabel(app.UIFigure);
            app.TrackIDEditFieldLabel.Text = 'Track ID';
            app.TrackIDEditField = uieditfield(app.UIFigure, 'text');
            app.TrackIDEditField.ValueChangedFcn = createCallbackFcn(app, @TrackIDEditFieldValueChanged, true);
            app.FeatureDistanceEditFieldLabel = uilabel(app.UIFigure);
            app.FeatureDistanceEditFieldLabel.Text = 'Feature Distance';
            app.FeatureDistanceEditField = uieditfield(app.UIFigure, 'text');
            app.FeatureDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @FeatureDistanceEditFieldValueChanged, true);
            app.ReloadOriginalsButton = uibutton(app.UIFigure, 'push');
            app.ReloadOriginalsButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadOriginalsButtonPushed, true);
            app.ReloadOriginalsButton.Text = {'Reload Originals'; ''};
            app.SavewithoriginalvaluesButton = uibutton(app.UIFigure, 'push');
            app.SavewithoriginalvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @SavewithoriginalvaluesButtonPushed, true);
            app.SavewithoriginalvaluesButton.Text = 'Save with original values';
            app.ContactWireTypeDropDownLabel = uilabel(app.UIFigure);
            app.ContactWireTypeDropDownLabel.Text = 'Contact Wire Type';
            app.ContactWireTypeDropDown = uidropdown(app.UIFigure);
            app.ContactWireTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ContactWireTypeDropDownValueChanged, true);
            app.SiteNameEditFieldLabel = uilabel(app.UIFigure);
            app.SiteNameEditFieldLabel.Text = 'Site Name';
            app.SiteNameEditField = uieditfield(app.UIFigure, 'text');
            app.SiteNameEditField.ValueChangedFcn = createCallbackFcn(app, @SiteNameEditFieldValueChanged, true);
            app.ELREditFieldLabel = uilabel(app.UIFigure);
            app.ELREditFieldLabel.Text = 'ELR';
            app.ELREditField = uieditfield(app.UIFigure, 'text');
            app.ELREditField.ValueChangedFcn = createCallbackFcn(app, @ELREditFieldValueChanged, true);
            app.ContactWireLabel = uilabel(app.UIFigure);
            app.ContactWireLabel.Text = 'ContactWire';
            app.ReloadDefaultsButton = uibutton(app.UIFigure, 'push');
            app.ReloadDefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadDefaultsButtonPushed, true);
            app.ReloadDefaultsButton.Text = 'Reload Defaults';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Save_data_win(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end