classdef Set_plot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UITable                  matlab.ui.control.Table
        UpdateValueButton        matlab.ui.control.Button
        ValueEditField           matlab.ui.control.EditField
        ValueEditFieldLabel      matlab.ui.control.Label
        FieldNameDropDown        matlab.ui.control.DropDown
        FieldNameDropDownLabel   matlab.ui.control.Label
        ExitandSetValuesButton   matlab.ui.control.Button
        ExitwithoutValuesButton  matlab.ui.control.Button
        ReloadDefaultsButton     matlab.ui.control.Button
        PlottingOptionsLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        plot_options2; 
        default_options;
        parent_app;
        plot_options2_table;
        saving_root;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_ap, struct_1, struct_2)
            app.plot_options2       = struct_1   ;
            app.default_options = struct_2   ;
            
            app.saving_root = getenv('APPDATA');
            
            app.parent_app = parent_ap;
            app.plot_options2_table = create_table_from_structure(app.plot_options2);
            app.UITable.Data = app.plot_options2_table;
            app.UITable.ColumnName = app.plot_options2_table.Properties.VariableNames;

            app.FieldNameDropDown.Items = fieldnames(app.plot_options2)';
            app.ValueEditField.Value = num2str(getfield(app.plot_options2,app.FieldNameDropDown.Value));

        end

        % Value changed function: ValueEditField
        function ValueEditFieldValueChanged(app, event)
            value = app.ValueEditField.Value;
            
        end

        % Button pushed function: UpdateValueButton
        function UpdateValueButtonPushed(app, event)
            %app.plot_options2
            if ~isempty(str2num(app.ValueEditField.Value))
            pass_val =  str2num(app.ValueEditField.Value); 
            else
            pass_val = app.ValueEditField.Value;
            end
            % ---------------------------------------
            % Have a check of the field value here
            % [messge_ , check_ok] = Check_Operator_settings(app.FieldNameDropDown.Value,pass_val);
            % ------------------------------------------
            app.plot_options2 = setfield(app.plot_options2,num2str(app.FieldNameDropDown.Value), pass_val);
            app.plot_options2_table = create_table_from_structure(app.plot_options2);
            app.UITable.Data = app.plot_options2_table;
        end

        % Button pushed function: ReloadDefaultsButton
        function ReloadDefaultsButtonPushed(app, event)
           [app.plot_options2,ok_] = load_structure_from_file([pwd,app.default_options.plot_options2_fname],....
           app.default_options.plot2_expected_fields);
           if ok_~= 1 ; msgbox('Warning:: structure fields dont match defaults') ;end      
           % now reset the display boxes and table
           app.plot_options2_table = create_table_from_structure(app.plot_options2);
           app.UITable.Data = app.plot_options2_table;
           app.ValueEditField.Value = num2str(getfield(app.plot_options2,app.FieldNameDropDown.Value));
   
        end

        % Button pushed function: ExitwithoutValuesButton
        function ExitwithoutValuesButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: ExitandSetValuesButton
        function ExitandSetValuesButtonPushed(app, event)

        saving_file_n_path =    [app.saving_root,app.default_options.plot_options2_fname];
        OK_ =  create_file_from_structure(app.plot_options2,saving_file_n_path);

        if ~OK_
        msgbox('file not saved (CHECK -- create_file_from_structure' )
        end 
        set_plotoptions2(app.parent_app ,app.plot_options2)

        delete(app)


        end

        % Value changed function: FieldNameDropDown
        function FieldNameDropDownValueChanged(app, event)
            value = app.FieldNameDropDown.Value;
            app.ValueEditField.Value = num2str(getfield(app.plot_options2,app.FieldNameDropDown.Value));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlottingOptionsLabel = uilabel(app.UIFigure);
            app.PlottingOptionsLabel.Text = 'Plotting Options';
            app.ReloadDefaultsButton = uibutton(app.UIFigure, 'push');
            app.ReloadDefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadDefaultsButtonPushed, true);
            app.ReloadDefaultsButton.Text = 'Reload Defaults';
            app.ExitwithoutValuesButton = uibutton(app.UIFigure, 'push');
            app.ExitwithoutValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ExitwithoutValuesButtonPushed, true);
            app.ExitwithoutValuesButton.Text = {'Exit without'; 'Values'};
            app.ExitandSetValuesButton = uibutton(app.UIFigure, 'push');
            app.ExitandSetValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ExitandSetValuesButtonPushed, true);
            app.ExitandSetValuesButton.Text = 'Exit and Set Values';
            app.FieldNameDropDownLabel = uilabel(app.UIFigure);
            app.FieldNameDropDownLabel.Text = 'Field Name';
            app.FieldNameDropDown = uidropdown(app.UIFigure);
            app.FieldNameDropDown.ValueChangedFcn = createCallbackFcn(app, @FieldNameDropDownValueChanged, true);
            app.ValueEditFieldLabel = uilabel(app.UIFigure);
            app.ValueEditFieldLabel.Text = 'Value';
            app.ValueEditField = uieditfield(app.UIFigure, 'text');
            app.ValueEditField.ValueChangedFcn = createCallbackFcn(app, @ValueEditFieldValueChanged, true);
            app.UpdateValueButton = uibutton(app.UIFigure, 'push');
            app.UpdateValueButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateValueButtonPushed, true);
            app.UpdateValueButton.Text = 'Update Value';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Set_plot(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end