classdef WireProApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LOCATION_NAMELabel              matlab.ui.control.Label
        SiteNameLabel                   matlab.ui.control.Label
        USER_NAMELabel                  matlab.ui.control.Label
        SettingsDoneLamp                matlab.ui.control.Lamp
        SettingsDoneLampLabel           matlab.ui.control.Label
        UserLabel                       matlab.ui.control.Label
        ResultsLamp                     matlab.ui.control.Lamp
        ResultsLampLabel                matlab.ui.control.Label
        TestProcessedLamp               matlab.ui.control.Lamp
        TestProcessedLampLabel          matlab.ui.control.Label
        TestLoadedLamp                  matlab.ui.control.Lamp
        TestLoadedLampLabel             matlab.ui.control.Label
        CONTACTWireTestLabel            matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        LoginButton                     matlab.ui.control.Button
        allowvaluechangesButton         matlab.ui.control.Button
        AcceptValuesButton              matlab.ui.control.Button
        TSSetLamp                       matlab.ui.control.Lamp
        TSSetLampLabel                  matlab.ui.control.Label
        OSSetLamp                       matlab.ui.control.Lamp
        OSSetLampLabel                  matlab.ui.control.Label
        SETLabel                        matlab.ui.control.Label
        VIEWDropDown                    matlab.ui.control.DropDown
        VIEWDropDownLabel               matlab.ui.control.Label
        TestSettingsButton              matlab.ui.control.Button
        OperatorsettingsButton          matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        TestDataTab                     matlab.ui.container.Tab
        loadexistingdatawithcurrentsettingsButton  matlab.ui.control.Button
        NotesEditField                  matlab.ui.control.EditField
        NotesEditFieldLabel             matlab.ui.control.Label
        ClearCurrentDataButton          matlab.ui.control.Button
        OperatorTextArea                matlab.ui.control.TextArea
        OperatorTextAreaLabel           matlab.ui.control.Label
        SiteNameTextArea                matlab.ui.control.TextArea
        SiteNameTextAreaLabel           matlab.ui.control.Label
        TestDateandTimeTextArea         matlab.ui.control.TextArea
        TestDateandTimeTextAreaLabel    matlab.ui.control.Label
        CurrentdatasetTextArea          matlab.ui.control.TextArea
        CurrentdatasetTextAreaLabel     matlab.ui.control.Label
        ViewTestsettingsButton          matlab.ui.control.Button
        ViewOperatorsettingsButton      matlab.ui.control.Button
        RunNewTestButton                matlab.ui.control.Button
        LoadExistingDataButton          matlab.ui.control.Button
        CheckProcessDataTab             matlab.ui.container.Tab
        setSNRoptionsButton             matlab.ui.control.Button
        viewSNRoptionsButton            matlab.ui.control.Button
        ChanneltimeandFrequencyviewerButton  matlab.ui.control.Button
        DisplaysignalqualityindictorsButton  matlab.ui.control.Button
        DisplaySNRBarchartsButton       matlab.ui.control.Button
        DisplaySNRMapsButton            matlab.ui.control.Button
        QuickCheckSNRButton             matlab.ui.control.Button
        SaveProcessedDataButton         matlab.ui.control.Button
        SetProcOptionsButton            matlab.ui.control.Button
        DataProcessedLamp               matlab.ui.control.Lamp
        DataProcessedLampLabel          matlab.ui.control.Label
        ClearProcessedDataButton        matlab.ui.control.Button
        ProcessDataButton               matlab.ui.control.Button
        ViewProcOptionsButton           matlab.ui.control.Button
        ResultsPredictionsTab           matlab.ui.container.Tab
        ProduceReportButton             matlab.ui.control.Button
        PLamp                           matlab.ui.control.Lamp
        PLampLabel                      matlab.ui.control.Label
        Learning_set_Label              matlab.ui.control.Label
        ViewPlotOptionsButton           matlab.ui.control.Button
        PREDICTIONButton                matlab.ui.control.Button
        logLikelyhoodTableButton        matlab.ui.control.Button
        DistanceMeanTableButton         matlab.ui.control.Button
        PredictionsButton               matlab.ui.control.Button
        ModeMaptagsButton               matlab.ui.control.Button
        InteractiveModeMapViewerButton  matlab.ui.control.Button
        MeanSTDofmodesButton            matlab.ui.control.Button
        ViewModeMapButton               matlab.ui.control.Button
        ModePairViewerfulldistanceButton  matlab.ui.control.Button
        ShowPeakFindButton              matlab.ui.control.Button
        ShowDispersionButton            matlab.ui.control.Button
        ShowMeshButton                  matlab.ui.control.Button
        SetPlotOptionsButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
    Property % Description
    
    %drive_letter_root           = 'C:\MLPROGS\';
        
    operator_sub_inst = []; 
    TestSettings_sub_inst = [];


    %   location_of_default_options = 'C:\MLPROGS\WirePro2\SETTINGS_FILES\default_options.dat';  
    location_of_default_options =  '\SETTINGS_FILES\default_options.dat';
    current_test_folder         =  '';
    default_option_fields       =  {'raw_data_file_options';...
                                   'raw_data_file_type';...
                                   'proc_options_fname';...
                                   'plot_options_fname';...
                                   'Test_Settings_fname';...
                                   'Operator_Settings_fname';...
                                   'plot_options2_fname';...
                                   'snr_settings_fname';...
                                   'raw_data_file_path';...
                                   'proc_expected_fields';...
                                   'plot_expected_fields';...
                                   'Test_expected_fields';...
                                   'Operator_expected_fields';...
                                   'plot2_expected_fields';...
                                   'snr_expected_fields'};

    slab_root     = [getenv('systemDrive'),getenv('homePath')]     ;
    settings_root = [getenv('APPDATA')];


    default_options     = [];
    proc_options        = [];
    plot_options        = [];
    test_settings       = [];
    operator_settings   = [];
    plot_options2       = []; 
    snr_settings        = [];
    
              
    current_status      = 0;
    operator_settings_done = 0  ;
    test_settings_done = 0      ;

    lights_status = [];
    panal_1_status = []; 
    panal_2_status = []; 
    panal_3_status = [];  
    panal_4_status = [];
    
    % set_panel_4

    % Lights:
    % 1 = [1 0 0]                 - red
    % 2 = [0 1 0]                 - green
    % 3 = [1.00  0.41  0.16] -amber

    % buttons
    % 1  -  visible
    % 0 -  greyd

    Save_data_win_wait  = 0;
    operator_settings_table ;    
    proc_options_table      ;
    plot_options_table      ;
    plot_options2_table      ;
    test_settings_sampling_table 
    test_settings_Impedence_table
    test_settings_Toneburst_table
    test_settings_Instrument_table
    test_settings_Model_table
    test_data
    rail_tester
    
    %test_settings_table    ;
    end
    

    methods (Access = public)
        function set_operatorsettings(app, struct_)
                      
            app.operator_settings = struct_;
            app.OperatorsettingsButton.Enable =  'on';
            app.operator_settings_table = create_table_from_structure(app.operator_settings);
            app.Update_Table();
            app.operator_settings_done   = 1      ;
            
            if app.test_settings_done       == 0  
            app.current_status = 0.25;
            else
            app.current_status = 1;
            end
           
            app.Set_Status();
            %app.Update_Status();

        end
     
        function set_testsettings(app,struct_)
            app.test_settings = struct_;
            app.TestSettingsButton.Enable =  'on';
            app.test_settings_sampling_table  =  create_table_from_structure(app.test_settings.Sampling);
            app.Update_Table();
            app.test_settings_done       = 1      ;

            if app.operator_settings_done       == 0  
            app.current_status = 0.75;
            else
            app.current_status = 1;
            end
             
            app.Set_Status();
            %app.Update_Status();
            
        end
        
        function set_procsettings(app ,struct_)
            app.proc_options = struct_;
            app.proc_options_table   =create_table_from_structure(app.proc_options);
            app.Update_Table();
        end

        function set_snrsettings(app ,struct_)
            app.snr_settings = struct_;
            %app.proc_options_table   =create_table_from_structure(app.proc_options);
            %app.Update_Table();
        end
        
        function set_plotoptions2(app ,struct_)
            app.plot_options2 = struct_;
            txt_ = app.plot_options2.Data_path_options{app.plot_options2.Data_path_choice};    
            app.Learning_set_Label.Text = ['PREDICTION DB: ',txt_(max(find(txt_=='\'))+1:end),'.'];

        end
        
        function set_operatorsettings_simple(app, struct_)
            % simple version with no status change
            app.operator_settings = struct_;
            app.OperatorsettingsButton.Enable =  'on';
            app.operator_settings_table = create_table_from_structure(app.operator_settings);
            app.Update_Table();
            app.operator_settings_done   = 1      ; 
            
            app.Save_data_win_wait       = 0      ;  

        end
        end
       
    methods (Access = private)
        
 function  Update_Table(app)
     
switch(app.VIEWDropDown.Value)

case('Operator Settings')
app.UITable.Data            =      app.operator_settings_table;
app.UITable.ColumnName      =      app.operator_settings_table.Properties.VariableNames;
app.UITable.FontSize        =      12;
    case('Proc Options')
app.UITable.Data            =      app.proc_options_table;
app.UITable.ColumnName      =      app.proc_options_table.Properties.VariableNames;
app.UITable.FontSize        =      12;
    case('Plot Options')
app.UITable.Data            =      app.plot_options_table;
app.UITable.ColumnName      =      app.plot_options_table.Properties.VariableNames;
app.UITable.FontSize        =      10;  

case('Test_samples')
app.UITable.Data           =       app.test_settings_sampling_table;      
app.UITable.ColumnName     =       app.test_settings_sampling_table.Properties.VariableNames;
app.UITable.FontSize       =       12;

case('Test_Impedence')
app.UITable.Data           =       app.test_settings_Impedence_table;      
app.UITable.ColumnName     =       app.test_settings_Impedence_table.Properties.VariableNames;
app.UITable.FontSize       =       12;
    
case('Test_Toneburst')    
app.UITable.Data           =       app.test_settings_Toneburst_table;      
app.UITable.ColumnName     =       app.test_settings_Toneburst_table     .Properties.VariableNames;
app.UITable.FontSize       =       12;
    
case('Test_Instrument')
app.UITable.Data           =       app.test_settings_Instrument_table;      
app.UITable.ColumnName     =       app.test_settings_Instrument_table.Properties.VariableNames;
app.UITable.FontSize       =       12;
    
case('Test_Model')    

app.UITable.Data           =       app.test_settings_Model_table;      
app.UITable.ColumnName     =       app.test_settings_Model_table.Properties.VariableNames;
app.UITable.FontSize       =       12;

end %switch(app.VIEWDropDown.Value)

app.USER_NAMELabel.Text     = app.operator_settings.Name;
app.LOCATION_NAMELabel.Text = app.operator_settings.Site_name;

 end
%------------------------------------------------------------------------------------------------------
function set_lights(app)
status_handles = {'app.SettingsDoneLamp.Color','app.TestLoadedLamp.Color','app.TestProcessedLamp.Color','app.ResultsLamp.Color'} ;
for index = 1:4

 switch app.lights_status(index)
      case(1)
      vals_temp = [1 0 0];   %red        
      case(2)
      vals_temp = [0 1 0];   %green            
      case(3)
      vals_temp = [1.00  0.41  0.16] ; %amber                   
      otherwise
      msgbox('warning light status wrong setting')  
 end %switch app.lights_status(index)
eval([status_handles{index},' = ', '[',num2str(vals_temp),'];'])
vals_temp = NaN;
end %for index = 1:4
end %function set_lights(app)
%------------------------------------------------------------------------------------------------------
function set_panel_1(app)
% 2 lights and 4 buttons
status_handles = {'app.OSSetLamp.Color','app.TSSetLamp.Color','app.ViewOperatorsettingsButton.Enable','app.ViewTestsettingsButton.Enable',...
'app.AcceptValuesButton.Enable','app.allowvaluechangesButton.Enable'};

for index = 1:2
switch app.panal_1_status(index)
      case(1)
      vals_temp = [1 0 0];   %red        
      case(2)
      vals_temp = [0 1 0];   %green            
      case(3)
      vals_temp = [1.00  0.41  0.16] ; %amber                   
      otherwise
      msgbox('panel 1 light status wrong setting')  
 end %switch app.lights_status(index)

eval([status_handles{index},' = ', '[',num2str(vals_temp),'];'])
vals_temp = NaN;

end %for index = 1:2

for index = 3:6
switch app.panal_1_status(index)
      case(1)
      vals_temp = 'on' ;   %active        
      case(0)
      vals_temp = 'off' ;   %grey   
    otherwise
      msgbox('panel 1 button status wrong setting')  
 end %switch app.lights_status(index)

eval([status_handles{index},' = ','''',vals_temp,''';' ])
vals_temp = NaN;

end %for index = 3:6

end %function set_panel_1
%------------------------------------------------------------------------------------------------------
function set_panel_2(app)
%  6 buttons
status_handles = {'app.RunNewTestButton.Enable','app.LoadExistingDataButton.Enable','app.loadexistingdatawithcurrentsettingsButton.Enable',...
'app.ViewOperatorsettingsButton.Enable','app.ViewTestsettingsButton.Enable','app.ClearCurrentDataButton.Enable'};

for index = 1:6
switch app.panal_2_status(index)
      case(1)
      vals_temp = 'on' ;   %active        
      case(0)
      vals_temp = 'off' ;   %grey  
    otherwise
      msgbox('panel 2 button status wrong setting')  

 end %switch app.lights_status(index)

eval([status_handles{index},' = ','''',vals_temp,''';' ])
vals_temp = NaN;
end %for index = 1:6

end %function set_panel_2
%------------------------------------------------------------------------------------------------------
function set_panel_3(app)
%  1 lights  12 buttons
 status_handles = {'app.DataProcessedLamp.Color','app.ViewProcOptionsButton.Enable','app.viewSNRoptionsButton.Enable','app.SetProcOptionsButton.Enable',...
'app.setSNRoptionsButton.Enable','app.QuickCheckSNRButton.Enable','app.ProcessDataButton.Enable','app.DisplaySNRMapsButton.Enable','app.DisplaySNRBarchartsButton.Enable',...
'app.ChanneltimeandFrequencyviewerButton.Enable','app.DisplaysignalqualityindictorsButton.Enable','app.SaveProcessedDataButton.Enable','app.ClearProcessedDataButton.Enable'};

index = 1;
switch app.panal_3_status(index)
      case(1)
      vals_temp = [1 0 0];   %red        
      case(2)
      vals_temp = [0 1 0];   %green            
      case(3)
      vals_temp = [1.00  0.41  0.16] ; %amber                   
      otherwise
      msgbox('panel 3 light status wrong setting')  
 end %switch app.lights_status(index)
eval([status_handles{index},' = ', '[',num2str(vals_temp),'];'])
vals_temp = NaN;


for index = 2:13
switch app.panal_3_status(index)
      case(1)
      vals_temp = 'on' ;   %active        
      case(0)
      vals_temp = 'off' ;   %grey  
    otherwise
      msgbox('panel 3 button status wrong setting')  
 end %switch app.lights_status(index)

eval([status_handles{index},' = ','''',vals_temp,''';' ])
vals_temp = NaN;
end %for index = 1:6

end %function set_panel_3
%------------------------------------------------------------------------------------------------------
function set_panel_4(app)
status_handles = {'app.ViewPlotOptionsButton.Enable','app.SetPlotOptionsButton.Enable','app.ModePairViewerfulldistanceButton.Enable',...
'app.ViewModeMapButton.Enable','app.MeanSTDofmodesButton.Enable','app.PredictionsButton.Enable','app.ShowPeakFindButton.Enable',...
'app.DistanceMeanTableButton.Enable','app.logLikelyhoodTableButton.Enable','app.ModeMaptagsButton.Enable','app.InteractiveModeMapViewerButton.Enable',...
'app.ShowDispersionButton.Enable','app.ShowMeshButton.Enable','app.PREDICTIONButton.Enable'};

for index = 1:14
switch app.panal_4_status(index)
      case(1)
      vals_temp = 'on' ;   %active        
      case(0)
      vals_temp = 'off' ;   %grey 
    otherwise
      msgbox('panel 4 button status wrong setting')  
 end %switch app.lights_status(index)

eval([status_handles{index},' = ','''',vals_temp,''';' ])
vals_temp = NaN;
end %for index = 1:6

end % function set_panel_4(app)

function Set_Status(app)
switch (app.current_status)
case(0)
app.lights_status  = [ 1 , 1 , 1 , 1 ];
app.panal_1_status = [ 1 , 1, 1 , 1 , 1 , 1 ]; 
app.panal_2_status = [ 0 , 0, 0 , 0 , 0 , 0 ]; 
app.panal_3_status = [ 1 , 0 , 0 , 0 , 0 , 0 , 0 , 0, 0 , 0 , 0 , 0 , 0];  
app.panal_4_status = [ 0 , 0 , 0 , 0 , 0 , 0 , 0 , 0, 0 , 0 , 0 , 0 , 0 , 0 ]; 
Update_Status(app)

case(0.25)% Operator settings done but not test settings
app.lights_status  = [ 3 , 1 , 1 , 1 ];
app.panal_1_status = [ 2 , 1, 0 , 1 , 1 , 1 ];
app.panal_2_status = [ 0 , 0, 0 , 0 , 0 , 0 ]; 
app.panal_3_status = [ 1 , 0 , 0 , 0 , 0 , 0 , 0 , 0, 0 , 0 , 0 , 0 , 0];
app.panal_4_status = [ 0 , 0 , 0 , 0 , 0 , 0 , 0 , 0, 0 , 0 , 0 , 0 , 0 , 0 ]; 
Update_Status(app)    

case(0.75)% Test settings done but not operator settings
app.lights_status  = [ 3 , 1 , 1 , 1 ];
app.panal_1_status = [ 1 , 2, 1 , 0 , 1 , 1 ]; 
app.panal_2_status = [ 0 , 0, 0 , 0 , 0 , 0 ]; 
app.panal_3_status = [ 1 , 0 , 0 , 0 , 0 , 0 , 0 , 0, 0 , 0 , 0 , 0 , 0];  
app.panal_4_status = [ 0 , 0 , 0 , 0 , 0 , 0 , 0 , 0, 0 , 0 , 0 , 0 , 0 , 0 ]; 
Update_Status(app)    

    case(1) % SETTINGS %settings done
app.lights_status  = [ 2 , 1 , 1 , 1 ];
app.panal_1_status = [ 2 , 2, 0 , 0 , 0 , 1 ]; 
app.panal_2_status = [ 1 , 1, 1 , 1 , 1 , 0 ]; 
app.panal_3_status = [ 1 , 0 , 0 , 0 , 0 , 0 , 0 , 0, 0 , 0 , 0 , 0 , 0];  
app.panal_4_status = [ 0 , 0 , 0 , 0 , 0 , 0 , 0 , 0, 0 , 0 , 0 , 0 , 0 , 0 ]; 
Update_Status(app)

    case(2)% DATA %test done or old data loaded
app.lights_status  = [ 2 , 2 , 1 , 1 ];
app.panal_1_status = [ 2 , 2, 0 , 0 , 0 , 1 ]; 
app.panal_2_status = [ 0 , 0, 0 , 0 , 0 , 1 ]; 
app.panal_3_status = [ 1 , 1 , 1 , 1 , 1 , 1 , 1 , 1, 1 , 1 , 1 , 0 , 0];  
app.panal_4_status = [ 0 , 0 , 0 , 0 , 0 , 0 , 0 , 0, 0 , 0 , 0 , 0 , 0 , 0 ]; 
Update_Status(app)

case(3)% Process DATA -- 
app.lights_status  = [ 2 , 2 , 2 , 1 ];
app.panal_1_status = [ 2 , 2, 0 , 0 , 0 , 1]; 
app.panal_2_status = [ 0 , 0, 0 , 0 , 0 , 1 ]; 
app.panal_3_status = [ 2 , 1 , 1 , 0 , 0 , 1 , 0 , 1, 1 , 1 , 1 , 1 , 1];  
app.panal_4_status = [ 1 , 1 , 1 , 1 , 1 , 1 , 1 , 1, 1 , 1 , 1 , 1 , 1 , 1 ]; 
Update_Status(app)

case(4) % DISPLAYED RESULTS+ REPORT -  just add the status light change
app.lights_status  = [ 2, 2 , 2 , 2 ];
app.panal_1_status = [ 2 , 2, 0 , 0 , 0 , 1 ]; 
app.panal_2_status = [ 0 , 0, 0 , 0 , 0 , 1 ]; 
app.panal_3_status = [ 2 , 1 , 1 , 0 , 0 , 1 , 0 , 1, 1 , 1 , 1 , 1 , 1];  
app.panal_4_status = [1 , 1 , 1 , 1 , 1 , 1 , 1 , 1, 1 , 1 , 1 , 1 , 1 , 1 ]; 
Update_Status(app)

end %switch (app.current_status)
end %function Set_Status(app)


function Update_Status(app)
set_lights(app)
set_panel_1(app)
set_panel_2(app)
set_panel_3(app)
set_panel_4(app)
end %function Update_Status(app)
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % slab_root 
            % mkdir(settings_root)
            % mkdir([settings_root,'\Operator_files'])
            % fprintf('hello\ntest\n') 

            %  **********************************************************
            %  The \SETTINGS_FILES  are in the  roaming area -   first check if that directory exists there and if not create it and make sure it has the Setting files in it 
            %  define the base area ABOVE
            %  have a message box showing what is done
            if exist([app.settings_root,'\SETTINGS_FILES']) ~= 7  
            mkdir(app.settings_root)
            end
            
            if exist([app.settings_root,'\SETTINGS_FILES\Operator_files']) ~= 7
            mkdir([app.settings_root,'\SETTINGS_FILES\Operator_files'])
            end %if([settings_root,'\Operator_files'])


            if exist([getenv('APPDATA'),'\SETTINGS_FILES\proc_options_.dat']) ~=2
            disp('proc options files')
            copyfile([pwd,'\SETTINGS_FILES\proc_options_.dat'],[app.settings_root,'\SETTINGS_FILES']); 
            end

            if exist([getenv('APPDATA'),'\SETTINGS_FILES\Test_Settings.dat']) ~=2
            disp('copying test settings')
            copyfile([pwd,'\SETTINGS_FILES\Test_Settings.dat'],[app.settings_root,'\SETTINGS_FILES']); 
            end 

            if exist([getenv('APPDATA'),'\SETTINGS_FILES\plot_options_.dat']) ~=2
            disp('copying plot options_ files')
            copyfile([pwd,'\SETTINGS_FILES\plot_options_.dat'],[app.settings_root,'\SETTINGS_FILES']); 
            end

            if exist([getenv('APPDATA'),'\SETTINGS_FILES\plot_options2.dat']) ~=2
            disp('copying plot options2 files')
            copyfile([pwd,'\SETTINGS_FILES\plot_options2.dat'],[app.settings_root,'\SETTINGS_FILES']); 
            end

            if exist([getenv('APPDATA'),'\SETTINGS_FILES\snr_settings.dat']) ~=2
            disp('copying snr files')
            copyfile([pwd,'\SETTINGS_FILES\snr_settings.dat'],[app.settings_root,'\SETTINGS_FILES']); 
            end

            if exist([app.settings_root,'\SETTINGS_FILES\Operator_files\Operator_Settings.dat']) ~= 2
            disp('copying operator files')                
            copyfile([pwd,'\SETTINGS_FILES\Operator_files\Operator_Settings.dat'],[app.settings_root,'\SETTINGS_FILES\Operator_files']);
            end %if exist([app.settings_root,'\Operator_files\Operator_Settings.dat']) ~= 2
 
            %  ********************************************************** 
            %  The \SLAB_data   is in the  [getenv('systemDrive'),getenv('homePath'),\SLAB_data ]  area-  first check it exist  

            if exist([app.slab_root,'\SLAB_data']) ~= 7
            mkdir([app.slab_root ,'\SLAB_data']) ; 
            end %if exist([getenv('systemDrive'),getenv('homePath'),'\SLAB_data']) ~= 7
            %  ********************************************************** 

            
            app.UIFigure.Icon='ICON2.png';
            app.UIFigure.Name = 'Traxion-NDT';

            app.default_options     =   load_structure_from_file([pwd,app.location_of_default_options],app.default_option_fields);


            app.proc_options        =   load_structure_from_file([app.settings_root,app.default_options.proc_options_fname],...
                                                          app.default_options.proc_expected_fields);

            app.plot_options        =   load_structure_from_file([app.settings_root,app.default_options.plot_options_fname],...
                                                         app.default_options.plot_expected_fields);

            app.plot_options2       =   load_structure_from_file([app.settings_root,app.default_options.plot_options2_fname],...
                                                         app.default_options.plot2_expected_fields);

            app.snr_settings        =   load_structure_from_file([app.settings_root,app.default_options.snr_settings_fname],...
                                                         app.default_options.snr_expected_fields);

            app.test_settings       =   load_structure_from_file([app.settings_root,app.default_options.Test_Settings_fname],...
                                                         app.default_options.Test_expected_fields);

            app.operator_settings   =   load_structure_from_file([app.settings_root,app.default_options.Operator_Settings_fname],...
                                                         app.default_options.Operator_expected_fields);
            
            
            app.operator_settings_table   = create_table_from_structure(app.operator_settings);
            
            % app.test_settings_table       = create_table_from_structure(app.test_settings); 
            app.proc_options_table   =create_table_from_structure(app.proc_options);
            app.plot_options_table   =create_table_from_structure(app.plot_options);
            
            app.test_settings_sampling_table      =  create_table_from_structure(app.test_settings.Sampling);
            app.test_settings_Impedence_table     =  create_table_from_structure(app.test_settings.Impedence);
            app.test_settings_Toneburst_table     =  create_table_from_structure(app.test_settings.Toneburst);
            app.test_settings_Instrument_table    =  create_table_from_structure(app.test_settings.Instrument_attributes);
            app.test_settings_Model_table         =  create_table_from_structure(app.test_settings.Model_data);
                        
            app.USER_NAMELabel.Text = app.operator_settings.Name;
            app.LOCATION_NAMELabel.Text = app.operator_settings.Site_name;
                      
                     
            %app.MMDistandceEditField.Value   =  num2str(app.plot_options.plot_distance);
            %app.TxEditField.Value            =  num2str(app.plot_options.tx_mode_princ);
            %app.RxEditField.Value            =  num2str(app.plot_options.rx_mode_princ); 


              app.Update_Table();
              app.Set_Status(); 

                %{
                app.RunNewTestButton.Enable             =  'off';
                app.LoadExistingDataButton.Enable       =  'off';
                app.ViewOperatorsettingsButton.Enable =  'off';
                app.ViewTestsettingsButton.Enable     =  'off';
                app. ClearCurrentDataButton.Enable     =  'off';

                app.ViewProcOptionsButton.Enable   =  'off';
                app.SetProcOptionsButton.Enable =  'off';
                app.ProcessDataButton.Enable             =  'off';
                app.ClearProcessedDataButton.Enable      =  'off';
                %}  

             app.Learning_set_Label.Text      =   '';
             app.PLamp.Color = [0.94,0.94,0.94];


        end

        % Button pushed function: OperatorsettingsButton
        function OperatorsettingsButtonPushed(app, event)
            %app.OperatorsettingsButton.Enable =  'off';
            app.operator_sub_inst = operator_sub(app, app.operator_settings, app.default_options);
            
            
            if app.current_status ==0.75
            app.current_status = 1 ;
            app.TabGroup.SelectedTab = app.TestDataTab;
            else
            app.current_status = 0.25 ; 
            end%if app.current_status ==0.75
            app.Set_Status();      


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            %delete(app.operator_sub_inst)
        end

        % Value changed function: VIEWDropDown
        function VIEWDropDownValueChanged(app, event)
            value = app.VIEWDropDown.Value;
            app.Update_Table();

        end

        % Drop down opening function: VIEWDropDown
        function VIEWDropDownOpening(app, event)
            
        end

        % Button pushed function: TestSettingsButton
        function TestSettingsButtonPushed(app, event)
            app.TestSettingsButton.Enable =  'off';
            
            if app.current_status ==0.25
            app.current_status = 1 ;
            app.TabGroup.SelectedTab = app.TestDataTab;
            else
            app.current_status = 0.75 ; 
            end%if app.current_status ==0.25
            app.Set_Status();   
            
            app.TestSettings_sub_inst = test_set_sub(app, app.test_settings, app.default_options);
        end

        % Button pushed function: LoadExistingDataButton
        function LoadExistingDataButtonPushed(app, event)

%cd([app.default_options.drive_letter_root , app.default_options.raw_data_file_path])

dummy_ = app.default_options.raw_data_file_path  ;
[file_,path_, ok_] = uigetfile([app.slab_root ,dummy_ , '\*.mat']);

if ok_ ==1
file_with_path  = [path_,file_];
dummy__ = load(file_with_path); 


if isfield(dummy__,'test_data')
app.test_data = dummy__.test_data ;    

if length(fields(app.test_data)) == 5  && isfield(app.test_data,'raw_data')&& isfield(app.test_data,'fixed_Test_Settings') &&...
isfield(app.test_data,'fixed_Operator_Settings')&& isfield(app.test_data,'file_with_path') && isfield(app.test_data,'date_time')  
%file has the correct number of field and they have the correct names

else
%file does not have the correct number of field and/or they do not have the correct names

end %if length(fields(test_data)) == 4  && isfield(test_data,'raw_data')  

else
msgbox('The file is not the correct format needs to have a field') 
end %if isfield(dummy__,'app.test_data')

app.current_test_folder = path_;
app.CurrentdatasetTextArea.Value = app.test_data.file_with_path;
app.TestDateandTimeTextArea.Value = datestr(datetime(app.test_data.date_time));
app.SiteNameTextArea.Value =  app.test_data.fixed_Operator_Settings.Site_name;
app.OperatorTextArea.Value =app.test_data.fixed_Operator_Settings.Name;
app.NotesEditField.Value  =  app.test_data.fixed_Operator_Settings.Notes;
app.current_status = 2;
app.Set_Status();

end % if ok_ ==1

        end

        % Button pushed function: RunNewTestButton
        function RunNewTestButtonPushed(app, event)
           [raw_data,err] =  run_alva_DAQ(app.test_settings)                               ;        
            
            if err==0
            %%%% allow changes to operator set    tings here   %%%%%%     
            % run the Save_data_win app               
                        
            clock_temp = fix(clock)  ;

            %save_answer = questdlg('Would you like to Save the test data?', ...
	        %'Options',...
            %'Save as is', 'Change OS and Save','Dont Save','Save as is');   
            
            save_answer = questdlg('Would you like to Save the test data?', ...
	        'Options',...
            'Save','Dont Save','Save');   
             
            %if strcmp(save_answer,'Change OS and Save' )
            %app.Save_data_win_wait = 1; 
            %Handle_ =  Save_data_win(app, app.operator_settings, app.default_options);
            %           waitfor(Handle_)
            %end % if strcmp(answer,'Change OS and Save' )
            %   wait atthis point until the  window opened and closed    


            switch (save_answer)
                case{'Save'}
            app.test_data.raw_data                 = raw_data                           ;
            app.test_data.fixed_Test_Settings      = app.test_settings                  ; 
            app.test_data.fixed_Operator_Settings  = app.operator_settings              ;
            app.test_data.date_time                = clock_temp                         ;
            
            app.test_data =   save_in_file_structure (app.slab_root, app.test_data,app.default_options); 
            app.current_test_folder = app.test_data.file_with_path(1:max(find(app.test_data.file_with_path=='\')));
            app.CurrentdatasetTextArea.Value = app.test_data.file_with_path;
            app.TestDateandTimeTextArea.Value = datestr(datetime(app.test_data.date_time));
            app.SiteNameTextArea.Value =  app.test_data.fixed_Operator_Settings.Site_name;
            app.OperatorTextArea.Value =app.test_data.fixed_Operator_Settings.Name;
            app.NotesEditField.Value  =  app.test_data.fixed_Operator_Settings.Notes;
            app.current_status = 2;
            app.Set_Status();
            
                case('Dont Save')
            %  no need to do anything
            % nothing saves and no change in status
            end % switch (save_answer)
           
            end % if err==0
           
        end

        % Button pushed function: ViewOperatorsettingsButton
        function ViewOperatorsettingsButtonPushed(app, event)
            View_operator(app, app.test_data.fixed_Operator_Settings)
        end

        % Button pushed function: ViewTestsettingsButton
        function ViewTestsettingsButtonPushed(app, event)
            view_test(app, app.test_data.fixed_Test_Settings)
        end

        % Button pushed function: ViewProcOptionsButton
        function ViewProcOptionsButtonPushed(app, event)
            view_proc(app, app.proc_options)            
        end

        % Button pushed function: SetProcOptionsButton
        function SetProcOptionsButtonPushed(app, event)
            set_proc(app, app.proc_options, app.default_options);
        end

        % Button pushed function: ProcessDataButton
        function ProcessDataButtonPushed(app, event)
          [app.rail_tester,loaded_fe_file_ok ] =  create_rail_tester_structure(app.default_options,app.test_data,app.proc_options);
          
          if loaded_fe_file_ok~= 1
          msgbox('warning issue with fe file loading')
          end %if loaded_fe_file_ok~= 1
          app.rail_tester                                =  fn_process_rail_data(app.rail_tester, app.proc_options);
          app.current_status = 3;
          txt_ = app.plot_options2.Data_path_options{app.plot_options2.Data_path_choice};    
          app.Learning_set_Label.Text = ['PREDICTION DB: ',txt_(max(find(txt_=='\'))+1:end),'.'];
         
          app.Set_Status();
        end

        % Button pushed function: ClearProcessedDataButton
        function ClearProcessedDataButtonPushed(app, event)
            app.current_status = 2;
            app.Set_Status();
            app.Learning_set_Label.Text      =   '';
            app.PLamp.Color = [0.94,0.94,0.94];
        end

        % Button pushed function: ClearCurrentDataButton
        function ClearCurrentDataButtonPushed(app, event)
            app.current_test_folder = '';
            app.CurrentdatasetTextArea.Value  = '' ;
            app.TestDateandTimeTextArea.Value = '' ;
            app.SiteNameTextArea.Value        = '' ;
            app.OperatorTextArea.Value        = '' ;
            app.NotesEditField.Value          = '' ;
            app.Learning_set_Label.Text      =   '';
            app.PLamp.Color = [0.94,0.94,0.94];
            app.current_status = 1;
            app.Set_Status();
        end

        % Callback function
        function ShowReportButtonPushed(app, event)
            app.current_status = 4;
            app.Set_Status();
        end

        % Callback function
        function ShowmodeMapButtonPushed(app, event)
        [grid_data] = fn_get_grid_data(app.rail_tester , app.plot_options); 
        %fn_plot_grid_data(grid_data,app.plot_options,app.operator_settings.feature_distance_m);    
        fn_plot_grid_data(grid_data,app.plot_options,app.plot_options.plot_distance);      
        end

        % Callback function
        function SelectedModePlotsButtonPushed(app, event)
            fn_plot_cw_result(app.rail_tester, app.plot_options);
        end

        % Button pushed function: ShowDispersionButton
        function ShowDispersionButtonPushed(app, event)
            showdispersion(app.plot_options,app.rail_tester);
        end

        % Button pushed function: ShowMeshButton
        function ShowMeshButtonPushed(app, event)
            show_mesh(app.rail_tester);
        end

        % Button pushed function: AcceptValuesButton
        function AcceptValuesButtonPushed(app, event)
            app.current_status = 1;
            app.Set_Status();
            app.TabGroup.SelectedTab = app.TestDataTab;
            
            % This line was entered by MJE on 18/1/24 to test the old git
            %  app.TestDataTabButtonDown2(app, event)  ---   doest work
            %  (private function)
            
        end

        % Button pushed function: SetPlotOptionsButton
        function SetPlotOptionsButtonPushed(app, event)
           Set_plot(app, app.plot_options2,app.default_options)  
          
          % app.plot_options2
          % app.ResultsPredictionsTab
          % app.Learning_set_Label
          % app.Learning_set_Label.Text                     
          

        end

        % Callback function
        function ShowPrincipalModeButtonPushed(app, event)
            temp_options = app.plot_options; 
            temp_options.tx_modes = temp_options.tx_mode_princ;     
            temp_options.rx_modes = temp_options.rx_mode_princ;   
            
            fn_plot_cw_result(app.rail_tester, temp_options);
            
            %[x,y] = ginput(1);
            % keyboard

        end

        % Callback function
        function SetMMButtonPushed(app, event)
            %app.MMDistandceEditField.Value   =  app.plot_options.plot_distance;
            if ~isempty(str2num(app.MMDistandceEditField.Value))
            pass_val =  str2num(app.MMDistandceEditField.Value); 
            %  check the value here
            app.plot_options.plot_distance = pass_val;    
           
            else
            msgbox('value should be a number- Not set')
            end 

        end

        % Callback function
        function SetPMButtonPushed(app, event)
             
             if  ~isempty(str2num(app.TxEditField.Value))
             pass_value = str2num(app.TxEditField.Value);
             %  check the value here 
             app.plot_options.tx_mode_princ = pass_value;
             else
             msgbox('Tx value should be a number- Not set')
             end

             if  ~isempty(str2num(app.RxEditField.Value))
             pass_value = str2num(app.RxEditField.Value);
             %  check the value here 
             app.plot_options.rx_mode_princ = pass_value;
             else
             msgbox('Rx value should be a number- Not set')
             end

        end

        % Button pushed function: allowvaluechangesButton
        function allowvaluechangesButtonPushed(app, event)
            app.current_status = 0;
            app.Set_Status();

        end

        % Button pushed function: SaveProcessedDataButton
        function SaveProcessedDataButtonPushed(app, event)

          save_processed_data(app.test_data ,app.rail_tester,app.current_test_folder , app.proc_options)
          %app.Update_Status();   
          
        end

        % Button pushed function: QuickCheckSNRButton
        function QuickCheckSNRButtonPushed(app, event)
            
            if isstruct(app.test_data)  
            rejection_indicators   = plot_SNR_Raw(app.test_data,[0,0,0,0],app.snr_settings);
            
            if length(find(rejection_indicators(1,:)<4)) 
            RMS_msg = ['Warning RMS flags on channels:'  num2str((find(rejection_indicators(1,:)<4))) ] ;   
            else
            RMS_msg = 'RMS flags OK'  ;
            end %if length(find(rejection_indicators(2,:)<3))  >0
            
            if length(find(rejection_indicators(2,:)<3)) 
            SNR_msg = ['Warning SNR flags on channels:'  num2str((find(rejection_indicators(2,:)<3))) ] ;   
            else
            SNR_msg = 'SNR flags OK'  ;
            end %if length(find(rejection_indicators(2,:)<3))  >0
            
            if length(find(rejection_indicators(3,:)<4)) 
            LFN_msg = ['Warning LFN flags on channels:'  num2str((find(rejection_indicators(3,:)<4))) ] ;   
            else
            LFN_msg = 'LFN flags OK'  ;
            end %if length(find(rejection_indicators(2,:)<3))  >0
            
            if length(find(rejection_indicators(4,:)<4)) 
            HFN_msg = ['Warning HFN flags on channels:'  num2str((find(rejection_indicators(4,:)<4))) ] ;   
            else
            HFN_msg = 'HFN flags OK'  ;
            end %if length(find(rejection_indicators(2,:)<3))  >0
                       

            msgbox({RMS_msg,SNR_msg,LFN_msg,HFN_msg})   
            end %if isstruct(app.test_data)  

        end

        % Button pushed function: DisplaySNRMapsButton
        function DisplaySNRMapsButtonPushed(app, event)
            %plot_list =   {'Maps','Bar Charts','indicators','time_freq_traces' } 
            
            if isstruct(app.test_data)  
            [~]   = plot_SNR_Raw(app.test_data,[1,0,0,0],app.snr_settings); 
            end %if isstruct(app.test_data)  
            
        end

        % Button pushed function: DisplaySNRBarchartsButton
        function DisplaySNRBarchartsButtonPushed(app, event)
                        %plot_list =   {'Maps','Bar Charts','indicators','time_freq_traces' } 
           if isstruct(app.test_data)   
           [~]   = plot_SNR_Raw(app.test_data,[0,1,0,0],app.snr_settings); 
           end %if isstruct(app.test_data)  
        end

        % Button pushed function: ChanneltimeandFrequencyviewerButton
        function ChanneltimeandFrequencyviewerButtonPushed(app, event)
                        %plot_list =   {'Maps','Bar Charts','indicators','time_freq_traces' } 
            if isstruct(app.test_data)  
                      [~]   = plot_SNR_Raw(app.test_data,[0,0,0,1],app.snr_settings); 
            end%if isstruct(app.test_data)  
        end

        % Button pushed function: DisplaysignalqualityindictorsButton
        function DisplaysignalqualityindictorsButtonPushed(app, event)
                        %plot_list =   {'Maps','Bar Charts','indicators','time_freq_traces' } 
                        if isstruct(app.test_data)  
            [~]   = plot_SNR_Raw(app.test_data,[0,0,1,0],app.snr_settings); 
                        end %if isstruct(app.test_data)  
        end

        % Button pushed function: ShowPeakFindButton
        function ShowPeakFindButtonPushed(app, event)

            if isstruct(app.rail_tester)   
            proc_file.rail_tester  =  app.rail_tester;
            proc_file.test_data    =  app.test_data  ;
            settings_              = app.plot_options2;
            do_plots = [1 0 0 0 0 0 0 0 0 0];
            LF = [pwd,settings_.Data_path_options{settings_.Data_path_choice}];
            
            [~] = plot_predict_processed(proc_file, settings_ ,do_plots, LF);        
            
            %plot_list =   {'Peak find','Mode pairs','Single Mode map','mean/std of modes','MM Viewer','Tag MMs','Predictions','DFM table','LL Table'};
            else
            msgbox('not')
            end %if isstruct(app.rail_tester)   

        end

        % Button pushed function: ModePairViewerfulldistanceButton
        function ModePairViewerfulldistanceButtonPushed(app, event)
            
            if isstruct(app.rail_tester)   
            proc_file.rail_tester  =  app.rail_tester;
            proc_file.test_data    =  app.test_data  ;
            settings_               = app.plot_options2;
            do_plots = [0 1 0 0 0 0 0 0 0 0];
            LF = [pwd,settings_.Data_path_options{settings_.Data_path_choice}];
            
            [~] = plot_predict_processed(proc_file, settings_ ,do_plots, LF);        
            end %if isstruct(app.rail_tester)   
            
            %plot_list =   {'Peak find','Mode pairs','Single Mode map','mean/std of modes','MM Viewer','Tag MMs','Predictions','DFM table','LL Table'};
        end

        % Button pushed function: ViewModeMapButton
        function ViewModeMapButtonPushed(app, event)
            if isstruct(app.rail_tester)   
            proc_file.rail_tester  =  app.rail_tester;
            proc_file.test_data    =  app.test_data  ;
            settings_              = app.plot_options2;
            do_plots = [0 0 1 0 0 0 0 0 0 0];
            LF = [pwd,settings_.Data_path_options{settings_.Data_path_choice}];
            
            [~] = plot_predict_processed(proc_file, settings_ ,do_plots, LF);        
            end %if isstruct(app.rail_tester)   
            %plot_list =   {'Peak find','Mode pairs','Single Mode map','mean/std of modes','MM Viewer','Tag MMs','Predictions','DFM table','LL Table'};
        end

        % Button pushed function: MeanSTDofmodesButton
        function MeanSTDofmodesButtonPushed(app, event)
            if isstruct(app.rail_tester)   
            proc_file.rail_tester  =  app.rail_tester;
            proc_file.test_data    =  app.test_data  ;
            settings_              = app.plot_options2;
            do_plots = [0 0 0 1 0 0 0 0 0 0];
            LF = [pwd,settings_.Data_path_options{settings_.Data_path_choice}];
            
            [~] = plot_predict_processed(proc_file, settings_ ,do_plots, LF);        
            end %if isstruct(app.rail_tester)   
            %plot_list =   {'Peak find','Mode pairs','Single Mode map','mean/std of modes','MM Viewer','Tag MMs','Predictions','DFM table','LL Table'};
        end

        % Button pushed function: InteractiveModeMapViewerButton
        function InteractiveModeMapViewerButtonPushed(app, event)
            if isstruct(app.rail_tester)    
            proc_file.rail_tester  =  app.rail_tester;
            proc_file.test_data    =  app.test_data  ;
            settings_              = app.plot_options2;
            do_plots = [0 0 0 0 1 0 0 0 0 0];
            LF = [pwd,settings_.Data_path_options{settings_.Data_path_choice}];
            
            [~] = plot_predict_processed(proc_file, settings_ ,do_plots, LF);        
            end %if isstruct(app.rail_tester)   
            %plot_list =   {'Peak find','Mode pairs','Single Mode map','mean/std of modes','MM Viewer','Tag MMs','Predictions','DFM table','LL Table'};
        end

        % Button pushed function: ModeMaptagsButton
        function ModeMaptagsButtonPushed(app, event)
            if isstruct(app.rail_tester)   
            proc_file.rail_tester  =  app.rail_tester;
            proc_file.test_data    =  app.test_data  ;
            settings_              = app.plot_options2;
            do_plots = [0 0 0 0 0 1 0 0 0 0];
            LF = [pwd,settings_.Data_path_options{settings_.Data_path_choice}];
            
            [~] = plot_predict_processed(proc_file, settings_ ,do_plots, LF);        
            end %if isstruct(app.rail_tester)   
            %plot_list =   {'Peak find','Mode pairs','Single Mode map','mean/std of modes','MM Viewer','Tag MMs','Predictions','DFM table','LL Table'};
        end

        % Button pushed function: PredictionsButton
        function PredictionsButtonPushed(app, event)
            if isstruct(app.rail_tester)    
            proc_file.rail_tester  =  app.rail_tester;
            proc_file.test_data    =  app.test_data  ;
            settings_              = app.plot_options2;
            do_plots = [0 0 0 0 0 0 1 0 0 0];
            LF = [pwd,settings_.Data_path_options{settings_.Data_path_choice}];
            
            [~] = plot_predict_processed(proc_file, settings_ ,do_plots, LF);        
            end %if isstruct(app.rail_tester)   
            %plot_list =   {'Peak find','Mode pairs','Single Mode map','mean/std of modes','MM Viewer','Tag MMs','Predictions','DFM table','LL Table'};
        end

        % Button pushed function: DistanceMeanTableButton
        function DistanceMeanTableButtonPushed(app, event)
            if isstruct(app.rail_tester)   
            proc_file.rail_tester  =  app.rail_tester;
            proc_file.test_data    =  app.test_data  ;
            settings_              = app.plot_options2;
            do_plots = [0 0 0 0 0 0 0 1 0 0];
            LF = [pwd,settings_.Data_path_options{settings_.Data_path_choice}];
            
            [~] = plot_predict_processed(proc_file, settings_ ,do_plots, LF);        
            end %if isstruct(app.rail_tester)   
            %plot_list =   {'Peak find','Mode pairs','Single Mode map','mean/std of modes','MM Viewer','Tag MMs','Predictions','DFM table','LL Table'};
        end

        % Button pushed function: logLikelyhoodTableButton
        function logLikelyhoodTableButtonPushed(app, event)
            if isstruct(app.rail_tester)    
            proc_file.rail_tester  =  app.rail_tester;
            proc_file.test_data    =  app.test_data  ;
            settings_              = app.plot_options2;
            do_plots = [0 0 0 0 0 0 0 0 1 0];
            LF = [pwd,settings_.Data_path_options{settings_.Data_path_choice}];
            
            [~] = plot_predict_processed(proc_file, settings_ ,do_plots, LF);        
            end %if isstruct(app.rail_tester)   

            %plot_list =   {'Peak find','Mode pairs','Single Mode map','mean/std of modes','MM Viewer','Tag MMs','Predictions','DFM table','LL Table'};
        end

        % Button pushed function: PREDICTIONButton
        function PREDICTIONButtonPushed(app, event)
            if isstruct(app.rail_tester)    
            proc_file.rail_tester  =  app.rail_tester;
            proc_file.test_data    =  app.test_data  ;
            settings_              = app.plot_options2;
            do_plots = [0 0 0 0 0 0 0 0 0 1];
            LF = [pwd,settings_.Data_path_options{settings_.Data_path_choice}];
            
            [pred_] = plot_predict_processed(proc_file, settings_ ,do_plots, LF);        
            RGB_colours = [0,1,0; 1 0.7 0  ;1,0,0];
                        
            app.PLamp.Color = RGB_colours(pred_.pred_value,:);
            app.current_status = 4;
            app.Set_Status();

            end %if isstruct(app.rail_tester)   
        end

        % Callback function
        function ButtonPushed(app, event)
         if isstruct(app.rail_tester)   
            
            proc_file.rail_tester  =  app.rail_tester;
            proc_file.test_data    =  app.test_data  ;
            settings_              = app.plot_options2;
            do_plots = [0 1 0 0 0 0 0 0 0 0];
            LF = [pwd,settings_.Data_path_options{settings_.Data_path_choice}];
            [~] = plot_predict_processed(proc_file, settings_ ,do_plots, LF);        
            
            %plot_list =   {'Peak find','Mode pairs','Single Mode map','mean/std of modes','MM Viewer','Tag MMs','Predictions','DFM table','LL Table'};
            else
            msgbox('not')
            end %if isstruct(app.rail_tester)      
        end

        % Button pushed function: viewSNRoptionsButton
        function viewSNRoptionsButtonPushed(app, event)
            view_snr(app, app.snr_settings)  
        end

        % Button pushed function: setSNRoptionsButton
        function setSNRoptionsButtonPushed(app, event)
            set_snr(app, app.snr_settings, app.default_options);
        end

        % Button pushed function: ViewPlotOptionsButton
        function ViewPlotOptionsButtonPushed(app, event)
            view_plot(app, app.plot_options2)  
        end

        % Button pushed function: loadexistingdatawithcurrentsettingsButton
        function loadexistingdatawithcurrentsettingsButtonPushed(app, event)
  
dummy_ = app.default_options.raw_data_file_path  ;
[file_,path_, ok_] = uigetfile([app.slab_root ,dummy_ , '\*.mat']);

if ok_ ==1
file_with_path  = [path_,file_];

dummy__ = load(file_with_path); 

if isfield(dummy__,'test_data')
app.test_data = dummy__.test_data ;    

if length(fields(app.test_data)) == 5  && isfield(app.test_data,'raw_data')&& isfield(app.test_data,'fixed_Test_Settings') &&...
isfield(app.test_data,'fixed_Operator_Settings')&& isfield(app.test_data,'file_with_path') && isfield(app.test_data,'date_time')  
%file has the correct number of field and they have the correct names
else

%file does not have the correct number of field and/or they do not have the correct names
end %if length(fields(test_data)) == 4  && isfield(test_data,'raw_data')  
else
msgbox('The file is not the correct format needs to have a field') 
end %if isfield(dummy__,'app.test_data')

      
app.test_data.fixed_Operator_Settings   =  app.operator_settings  ;
app.test_data.fixed_Test_Settings       =  app.test_settings      ;

app.current_test_folder = path_;
app.CurrentdatasetTextArea.Value = app.test_data.file_with_path;
app.TestDateandTimeTextArea.Value = datestr(datetime(app.test_data.date_time));
app.SiteNameTextArea.Value =  app.test_data.fixed_Operator_Settings.Site_name;
app.OperatorTextArea.Value =app.test_data.fixed_Operator_Settings.Name;
app.NotesEditField.Value  =  app.test_data.fixed_Operator_Settings.Notes;
app.current_status = 2;
app.Set_Status();

end % if ok_ ==1

        end

        % Button pushed function: ProduceReportButton
        function ProduceReportButtonPushed(app, event)
              
            OS = app.operator_settings;
            proc_file.rail_tester  =  app.rail_tester;
            proc_file.test_data    =  app.test_data  ;
                       
            settings_              = app.plot_options2;  
            do_plots = [0 0 0 0 0 0 0 0 0 0];
            LF = [pwd,settings_.Data_path_options{settings_.Data_path_choice}];
            
            [pred_] = plot_predict_processed(proc_file, settings_ ,do_plots, LF);        
             

            % 
            wire_types = {'120','107'};

            DT_S = string(datetime('now'));
            
            report_txt= '';
            report_txt= [report_txt,'----------------------------\n'];
            report_txt= [report_txt,'----------------------------\n'];
            report_txt= [report_txt,'Neutral Section Clamp Report\n'];
            report_txt= [report_txt, DT_S{1},'\n'];
            report_txt= [report_txt,'Tester: ',OS.Name,',(',num2str(OS.Sentinel_No),').\n'];
            report_txt= [report_txt,'----------------------------\n'];
            report_txt= [report_txt,'Tower ID: ' OS.Tower_ID,'\n'];
            report_txt= [report_txt,'ELR:',num2str(OS.ELR),'\n'];
            report_txt= [report_txt,'Milage: ',num2str(OS.Milage),  '\n'];
            report_txt= [report_txt,'Track ID: ', num2str(OS.Track_ID),'\n'];
            report_txt= [report_txt,'Wire Type: ',wire_types{OS.contact_wire_type_120_107},'\n'];
            report_txt= [report_txt,'----------------------------\n'];
            report_txt= [report_txt,'Prediction: ', pred_.Labels{pred_.pred_value},'\n'];
            report_txt= [report_txt,'----------------------------\n'];
            report_txt= [report_txt,'----------------------------\n'];
            
            fprintf(report_txt)
                                        

        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
%


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.UITable = uitable(app.SettingsTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.OperatorsettingsButton = uibutton(app.SettingsTab, 'push');
            app.OperatorsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @OperatorsettingsButtonPushed, true);
            app.OperatorsettingsButton.Text = 'Operator settings';
            app.TestSettingsButton = uibutton(app.SettingsTab, 'push');
            app.TestSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @TestSettingsButtonPushed, true);
            app.TestSettingsButton.Text = 'Test Settings';
            app.VIEWDropDownLabel = uilabel(app.SettingsTab);
            app.VIEWDropDownLabel.Text = 'VIEW:';
            app.VIEWDropDown = uidropdown(app.SettingsTab);
            app.VIEWDropDown.Items = {'Operator Settings', 'Proc Options', 'Plot Options', 'Test_samples', 'Test_Impedence', 'Test_Toneburst', 'Test_Instrument', 'Test_Model'};
            app.VIEWDropDown.DropDownOpeningFcn = createCallbackFcn(app, @VIEWDropDownOpening, true);
            app.VIEWDropDown.ValueChangedFcn = createCallbackFcn(app, @VIEWDropDownValueChanged, true);
            app.VIEWDropDown.Value = 'Operator Settings';
            app.SETLabel = uilabel(app.SettingsTab);
            app.SETLabel.Text = 'SET:';
            app.OSSetLampLabel = uilabel(app.SettingsTab);
            app.OSSetLampLabel.Text = 'OS Set';
            app.OSSetLamp = uilamp(app.SettingsTab);
            app.TSSetLampLabel = uilabel(app.SettingsTab);
            app.TSSetLampLabel.Text = 'TS Set';
            app.TSSetLamp = uilamp(app.SettingsTab);
            app.AcceptValuesButton = uibutton(app.SettingsTab, 'push');
            app.AcceptValuesButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptValuesButtonPushed, true);
            app.AcceptValuesButton.Text = 'Accept Values';
            app.allowvaluechangesButton = uibutton(app.SettingsTab, 'push');
            app.allowvaluechangesButton.ButtonPushedFcn = createCallbackFcn(app, @allowvaluechangesButtonPushed, true);
            app.allowvaluechangesButton.Text = 'allow value changes';
            app.LoginButton = uibutton(app.SettingsTab, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.TestDataTab = uitab(app.TabGroup);
            app.TestDataTab.Title = 'Test /Data';
            app.LoadExistingDataButton = uibutton(app.TestDataTab, 'push');
            app.LoadExistingDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExistingDataButtonPushed, true);
            app.LoadExistingDataButton.Text = 'Load Existing Data';
            app.RunNewTestButton = uibutton(app.TestDataTab, 'push');
            app.RunNewTestButton.ButtonPushedFcn = createCallbackFcn(app, @RunNewTestButtonPushed, true);
            app.RunNewTestButton.Text = 'Run New Test';
            app.ViewOperatorsettingsButton = uibutton(app.TestDataTab, 'push');
            app.ViewOperatorsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewOperatorsettingsButtonPushed, true);
            app.ViewOperatorsettingsButton.Text = 'View Operator settings';
            app.ViewTestsettingsButton = uibutton(app.TestDataTab, 'push');
            app.ViewTestsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewTestsettingsButtonPushed, true);
            app.ViewTestsettingsButton.Text = 'View Test settings';
            app.CurrentdatasetTextAreaLabel = uilabel(app.TestDataTab);
            app.CurrentdatasetTextAreaLabel.Text = 'Current data set';
            app.CurrentdatasetTextArea = uitextarea(app.TestDataTab);
            app.TestDateandTimeTextAreaLabel = uilabel(app.TestDataTab);
            app.TestDateandTimeTextAreaLabel.Text = 'Test Date and Time';
            app.TestDateandTimeTextArea = uitextarea(app.TestDataTab);
            app.SiteNameTextAreaLabel = uilabel(app.TestDataTab);
            app.SiteNameTextAreaLabel.Text = 'Site Name';
            app.SiteNameTextArea = uitextarea(app.TestDataTab);
            app.OperatorTextAreaLabel = uilabel(app.TestDataTab);
            app.OperatorTextAreaLabel.Text = 'Operator';
            app.OperatorTextArea = uitextarea(app.TestDataTab);
            app.ClearCurrentDataButton = uibutton(app.TestDataTab, 'push');
            app.ClearCurrentDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearCurrentDataButtonPushed, true);
            app.ClearCurrentDataButton.Text = 'Clear Current Data';
            app.NotesEditFieldLabel = uilabel(app.TestDataTab);
            app.NotesEditFieldLabel.Text = 'Notes';
            app.NotesEditField = uieditfield(app.TestDataTab, 'text');
            app.loadexistingdatawithcurrentsettingsButton = uibutton(app.TestDataTab, 'push');
            app.loadexistingdatawithcurrentsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @loadexistingdatawithcurrentsettingsButtonPushed, true);
            app.loadexistingdatawithcurrentsettingsButton.Text = {'load existing data'; ' with current settings'};
            app.CheckProcessDataTab = uitab(app.TabGroup);
            app.CheckProcessDataTab.Title = 'Check/Process Data';
            app.ViewProcOptionsButton = uibutton(app.CheckProcessDataTab, 'push');
            app.ViewProcOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewProcOptionsButtonPushed, true);
            app.ViewProcOptionsButton.Text = 'View Proc Options';
            app.ProcessDataButton = uibutton(app.CheckProcessDataTab, 'push');
            app.ProcessDataButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessDataButtonPushed, true);
            app.ProcessDataButton.Text = 'Process Data';
            app.ClearProcessedDataButton = uibutton(app.CheckProcessDataTab, 'push');
            app.ClearProcessedDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearProcessedDataButtonPushed, true);
            app.ClearProcessedDataButton.Text = 'Clear Processed Data';
            app.DataProcessedLampLabel = uilabel(app.CheckProcessDataTab);
            app.DataProcessedLampLabel.Text = 'Data Processed ';
            app.DataProcessedLamp = uilamp(app.CheckProcessDataTab);
            app.SetProcOptionsButton = uibutton(app.CheckProcessDataTab, 'push');
            app.SetProcOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @SetProcOptionsButtonPushed, true);
            app.SetProcOptionsButton.Text = 'Set Proc Options';
            app.SaveProcessedDataButton = uibutton(app.CheckProcessDataTab, 'push');
            app.SaveProcessedDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProcessedDataButtonPushed, true);
            app.SaveProcessedDataButton.Text = 'Save Processed Data';
            app.QuickCheckSNRButton = uibutton(app.CheckProcessDataTab, 'push');
            app.QuickCheckSNRButton.ButtonPushedFcn = createCallbackFcn(app, @QuickCheckSNRButtonPushed, true);
            app.QuickCheckSNRButton.Text = {'Quick Check'; 'SNR +++'};
            app.DisplaySNRMapsButton = uibutton(app.CheckProcessDataTab, 'push');
            app.DisplaySNRMapsButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaySNRMapsButtonPushed, true);
            app.DisplaySNRMapsButton.Text = 'Display SNR++ Maps';
            app.DisplaySNRBarchartsButton = uibutton(app.CheckProcessDataTab, 'push');
            app.DisplaySNRBarchartsButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaySNRBarchartsButtonPushed, true);
            app.DisplaySNRBarchartsButton.Text = {'Display SNR +++ '; 'Bar charts'};
            app.DisplaysignalqualityindictorsButton = uibutton(app.CheckProcessDataTab, 'push');
            app.DisplaysignalqualityindictorsButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaysignalqualityindictorsButtonPushed, true);
            app.DisplaysignalqualityindictorsButton.Text = {'Display signal '; 'quality indictors'};
            app.ChanneltimeandFrequencyviewerButton = uibutton(app.CheckProcessDataTab, 'push');
            app.ChanneltimeandFrequencyviewerButton.ButtonPushedFcn = createCallbackFcn(app, @ChanneltimeandFrequencyviewerButtonPushed, true);
            app.ChanneltimeandFrequencyviewerButton.Text = {'Channel time'; 'and '; 'Frequency viewer'};
            app.viewSNRoptionsButton = uibutton(app.CheckProcessDataTab, 'push');
            app.viewSNRoptionsButton.ButtonPushedFcn = createCallbackFcn(app, @viewSNRoptionsButtonPushed, true);
            app.viewSNRoptionsButton.Text = 'view SNR options';
            app.setSNRoptionsButton = uibutton(app.CheckProcessDataTab, 'push');
            app.setSNRoptionsButton.ButtonPushedFcn = createCallbackFcn(app, @setSNRoptionsButtonPushed, true);
            app.setSNRoptionsButton.Text = 'set SNR options';
            app.ResultsPredictionsTab = uitab(app.TabGroup);
            app.ResultsPredictionsTab.Title = 'Results / Predictions';
            app.SetPlotOptionsButton = uibutton(app.ResultsPredictionsTab, 'push');
            app.SetPlotOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @SetPlotOptionsButtonPushed, true);
            app.SetPlotOptionsButton.Text = {'Set '; 'Plot Options'};
            app.ShowMeshButton = uibutton(app.ResultsPredictionsTab, 'push');
            app.ShowMeshButton.ButtonPushedFcn = createCallbackFcn(app, @ShowMeshButtonPushed, true);
            app.ShowMeshButton.Text = 'Show Mesh';
            app.ShowDispersionButton = uibutton(app.ResultsPredictionsTab, 'push');
            app.ShowDispersionButton.ButtonPushedFcn = createCallbackFcn(app, @ShowDispersionButtonPushed, true);
            app.ShowDispersionButton.Text = 'Show Dispersion';
            app.ShowPeakFindButton = uibutton(app.ResultsPredictionsTab, 'push');
            app.ShowPeakFindButton.ButtonPushedFcn = createCallbackFcn(app, @ShowPeakFindButtonPushed, true);
            app.ShowPeakFindButton.Text = 'Show Peak Find';
            app.ModePairViewerfulldistanceButton = uibutton(app.ResultsPredictionsTab, 'push');
            app.ModePairViewerfulldistanceButton.ButtonPushedFcn = createCallbackFcn(app, @ModePairViewerfulldistanceButtonPushed, true);
            app.ModePairViewerfulldistanceButton.Text = {'Mode Pair Viewer'; '(full distance)'};
            app.ViewModeMapButton = uibutton(app.ResultsPredictionsTab, 'push');
            app.ViewModeMapButton.ButtonPushedFcn = createCallbackFcn(app, @ViewModeMapButtonPushed, true);
            app.ViewModeMapButton.Text = {'View '; ' Mode Map'};
            app.MeanSTDofmodesButton = uibutton(app.ResultsPredictionsTab, 'push');
            app.MeanSTDofmodesButton.ButtonPushedFcn = createCallbackFcn(app, @MeanSTDofmodesButtonPushed, true);
            app.MeanSTDofmodesButton.Text = {'Mean / STD'; 'of modes'};
            app.InteractiveModeMapViewerButton = uibutton(app.ResultsPredictionsTab, 'push');
            app.InteractiveModeMapViewerButton.ButtonPushedFcn = createCallbackFcn(app, @InteractiveModeMapViewerButtonPushed, true);
            app.InteractiveModeMapViewerButton.Text = {'Interactive'; 'Mode Map'; 'Viewer'};
            app.ModeMaptagsButton = uibutton(app.ResultsPredictionsTab, 'push');
            app.ModeMaptagsButton.ButtonPushedFcn = createCallbackFcn(app, @ModeMaptagsButtonPushed, true);
            app.ModeMaptagsButton.Text = {'Mode Map'; 'tags'};
            app.PredictionsButton = uibutton(app.ResultsPredictionsTab, 'push');
            app.PredictionsButton.ButtonPushedFcn = createCallbackFcn(app, @PredictionsButtonPushed, true);
            app.PredictionsButton.Text = '3 Predictions';
            app.DistanceMeanTableButton = uibutton(app.ResultsPredictionsTab, 'push');
            app.DistanceMeanTableButton.ButtonPushedFcn = createCallbackFcn(app, @DistanceMeanTableButtonPushed, true);
            app.DistanceMeanTableButton.Text = {'Distance / Mean'; 'Table'};
            app.logLikelyhoodTableButton = uibutton(app.ResultsPredictionsTab, 'push');
            app.logLikelyhoodTableButton.ButtonPushedFcn = createCallbackFcn(app, @logLikelyhoodTableButtonPushed, true);
            app.logLikelyhoodTableButton.Text = {'log Likelyhood'; 'Table'};
            app.PREDICTIONButton = uibutton(app.ResultsPredictionsTab, 'push');
            app.PREDICTIONButton.ButtonPushedFcn = createCallbackFcn(app, @PREDICTIONButtonPushed, true);
            app.PREDICTIONButton.Text = 'PREDICTION';
            app.ViewPlotOptionsButton = uibutton(app.ResultsPredictionsTab, 'push');
            app.ViewPlotOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewPlotOptionsButtonPushed, true);
            app.ViewPlotOptionsButton.Text = {'View'; 'Plot Options'};
            app.Learning_set_Label = uilabel(app.ResultsPredictionsTab);
            app.PLampLabel = uilabel(app.ResultsPredictionsTab);
            app.PLampLabel.Text = 'P:';
            app.PLamp = uilamp(app.ResultsPredictionsTab);
            app.ProduceReportButton = uibutton(app.ResultsPredictionsTab, 'push');
            app.ProduceReportButton.ButtonPushedFcn = createCallbackFcn(app, @ProduceReportButtonPushed, true);
            app.ProduceReportButton.Text = 'Produce Report';
            app.CONTACTWireTestLabel = uilabel(app.UIFigure);
            app.CONTACTWireTestLabel.Text = 'CONTACT Wire-Test';
            app.TestLoadedLampLabel = uilabel(app.UIFigure);
            app.TestLoadedLampLabel.Text = 'Test Loaded';
            app.TestLoadedLamp = uilamp(app.UIFigure);
            app.TestProcessedLampLabel = uilabel(app.UIFigure);
            app.TestProcessedLampLabel.Text = 'Test Processed';
            app.TestProcessedLamp = uilamp(app.UIFigure);
            app.ResultsLampLabel = uilabel(app.UIFigure);
            app.ResultsLampLabel.Text = {'Results'; ''};
            app.ResultsLamp = uilamp(app.UIFigure);
            app.UserLabel = uilabel(app.UIFigure);
            app.UserLabel.Text = 'User';
            app.SettingsDoneLampLabel = uilabel(app.UIFigure);
            app.SettingsDoneLampLabel.Text = {'Settings'; 'Done'; ''};
            app.SettingsDoneLamp = uilamp(app.UIFigure);
            app.USER_NAMELabel = uilabel(app.UIFigure);
            app.USER_NAMELabel.Text = {'USER_NAME'; ''};
            app.SiteNameLabel = uilabel(app.UIFigure);
            app.SiteNameLabel.Text = 'Site Name';
            app.LOCATION_NAMELabel = uilabel(app.UIFigure);
            app.LOCATION_NAMELabel.Text = 'LOCATION_NAME';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WireProApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end