classdef WireProApp_BU < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LOCATION_NAMELabel             matlab.ui.control.Label
        SiteNameLabel                  matlab.ui.control.Label
        USER_NAMELabel                 matlab.ui.control.Label
        SettingsDoneLamp               matlab.ui.control.Lamp
        SettingsDoneLampLabel          matlab.ui.control.Label
        UserLabel                      matlab.ui.control.Label
        ResultsLamp                    matlab.ui.control.Lamp
        ResultsLampLabel               matlab.ui.control.Label
        TestProcessedLamp              matlab.ui.control.Lamp
        TestProcessedLampLabel         matlab.ui.control.Label
        TestLoadedLamp                 matlab.ui.control.Lamp
        TestLoadedLampLabel            matlab.ui.control.Label
        WIRETESTLabel                  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        SettingsTab                    matlab.ui.container.Tab
        allowvaluechangesButton        matlab.ui.control.Button
        AcceptValuesButton             matlab.ui.control.Button
        TSSetLamp                      matlab.ui.control.Lamp
        TSSetLampLabel                 matlab.ui.control.Label
        OSSetLamp                      matlab.ui.control.Lamp
        OSSetLampLabel                 matlab.ui.control.Label
        SETLabel                       matlab.ui.control.Label
        VIEWDropDown                   matlab.ui.control.DropDown
        VIEWDropDownLabel              matlab.ui.control.Label
        TestSettingsButton             matlab.ui.control.Button
        OperatorsettingsButton         matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        TestDataTab                    matlab.ui.container.Tab
        NotesEditField                 matlab.ui.control.EditField
        NotesEditFieldLabel            matlab.ui.control.Label
        ClearCurrentDataButton         matlab.ui.control.Button
        OperatorTextArea               matlab.ui.control.TextArea
        OperatorTextAreaLabel          matlab.ui.control.Label
        SiteNameTextArea               matlab.ui.control.TextArea
        SiteNameTextAreaLabel          matlab.ui.control.Label
        TestDateandTimeTextArea        matlab.ui.control.TextArea
        TestDateandTimeTextAreaLabel   matlab.ui.control.Label
        CurrentdatasetTextArea         matlab.ui.control.TextArea
        CurrentdatasetTextAreaLabel    matlab.ui.control.Label
        ViewTestsettingsButton         matlab.ui.control.Button
        ViewOperatorsettingsButton     matlab.ui.control.Button
        RunNewTestButton               matlab.ui.control.Button
        LoadExistingDataButton         matlab.ui.control.Button
        ProcessingTab                  matlab.ui.container.Tab
        SaveProcessedDataButton        matlab.ui.control.Button
        ChangeProcessingOptionsButton  matlab.ui.control.Button
        DataProcessedLamp              matlab.ui.control.Lamp
        DataProcessedLampLabel         matlab.ui.control.Label
        ClearProcessedDataButton       matlab.ui.control.Button
        ProcessDataButton              matlab.ui.control.Button
        ViewProcessingOptionsButton    matlab.ui.control.Button
        ResultsTab                     matlab.ui.container.Tab
        includeplotparametersCheckBox  matlab.ui.control.CheckBox
        includeprocessingparametersCheckBox  matlab.ui.control.CheckBox
        includeOperatorSettingsCheckBox  matlab.ui.control.CheckBox
        includemeshCheckBox            matlab.ui.control.CheckBox
        includeDispcurveCheckBox       matlab.ui.control.CheckBox
        includemodeplotsCheckBox       matlab.ui.control.CheckBox
        IncludePrincipalmodeCheckBox   matlab.ui.control.CheckBox
        IncludemodemapCheckBox         matlab.ui.control.CheckBox
        IncludeProcessingnotesinreoprtCheckBox  matlab.ui.control.CheckBox
        SetPMButton                    matlab.ui.control.Button
        SetMMButton                    matlab.ui.control.Button
        RxEditField                    matlab.ui.control.EditField
        RxEditFieldLabel               matlab.ui.control.Label
        TxEditField                    matlab.ui.control.EditField
        TxEditFieldLabel               matlab.ui.control.Label
        MMDistandceEditField           matlab.ui.control.EditField
        MMDistandceEditFieldLabel      matlab.ui.control.Label
        SaveReportButton               matlab.ui.control.Button
        SelectedModePlotsButton        matlab.ui.control.Button
        ShowReportButton               matlab.ui.control.Button
        ProcessingNotesTextArea        matlab.ui.control.TextArea
        ProcessingNotesTextAreaLabel   matlab.ui.control.Label
        ShowDispersionButton           matlab.ui.control.Button
        ShowPrincipalModeButton        matlab.ui.control.Button
        ShowMeshButton                 matlab.ui.control.Button
        ShowmodeMapButton              matlab.ui.control.Button
        ViewSetPlotOptionsButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
    Property % Description
    
    %drive_letter_root           = 'C:\MLPROGS\';
        
    operator_sub_inst = []; 
    TestSettings_sub_inst = [];
        
    %   location_of_default_options = 'C:\MLPROGS\WirePro2\SETTINGS_FILES\default_options.dat';  
    location_of_default_options =  '\SETTINGS_FILES\default_options.dat';

    
    current_test_folder         =  '';
    default_option_fields       =  {'raw_data_file_options';...
                                   'raw_data_file_type';...
                                   'proc_options_fname';...
                                   'plot_options_fname';...
                                   'Test_Settings_fname';...
                                   'Operator_Settings_fname';...
                                   'raw_data_file_path';...
                                   'proc_expected_fields';...
                                   'plot_expected_fields';...
                                   'Test_expected_fields';...
                                   'Operator_expected_fields'};

    slab_root     = [getenv('systemDrive'),getenv('homePath')]     ;
    settings_root = [getenv('APPDATA')];

    default_options     = [];
    proc_options        = [];
    plot_options        = [];
    test_settings       = [];
    operator_settings   = [];
    current_status      = 0;
    operator_settings_done = 0  ;
    test_settings_done = 0      ;
    
    Save_data_win_wait  = 0;
    operator_settings_table ;    
    proc_options_table      ;
    plot_options_table      ;
    test_settings_sampling_table 
    test_settings_Impedence_table
    test_settings_Toneburst_table
    test_settings_Instrument_table
    test_settings_Model_table
    test_data
    rail_tester
    
    %test_settings_table    ;
    end
    

    methods (Access = public)
        function set_operatorsettings(app, struct_)
                      
            app.operator_settings = struct_;
            app.OperatorsettingsButton.Enable =  'on';
            app.operator_settings_table = create_table_from_structure(app.operator_settings);
            app.Update_Table();
            
            app.operator_settings_done   = 1      ;
            
            if app.test_settings_done       == 0  
            app.current_status = 0.25;
            else
            app.current_status = 1;
            end
           
            app.Update_Status();
        end
     
        function set_testsettings(app,struct_)
            app.test_settings = struct_;
            app.TestSettingsButton.Enable =  'on';
            app.test_settings_sampling_table  =  create_table_from_structure(app.test_settings.Sampling);
            app.Update_Table();
            app.test_settings_done       = 1      ;
            if app.operator_settings_done       == 0  
            app.current_status = 0.75;
            else
            app.current_status = 1;
            end
                       
            app.Update_Status();
            
        end
        
        function set_procsettings(app ,struct_)
            app.proc_options = struct_;
            app.proc_options_table   =create_table_from_structure(app.proc_options);
            app.Update_Table();
        end
        
        function set_plotoptions(app ,struct_)
            app.plot_options = struct_;
            app.plot_options_table   =create_table_from_structure(app.plot_options);
            app.MMDistandceEditField.Value   =  num2str(app.plot_options.plot_distance);
            app.TxEditField.Value            =  num2str(app.plot_options.tx_mode_princ);
            app.RxEditField.Value            =  num2str(app.plot_options.rx_mode_princ); 
            app.Update_Table();

        end
        
        function set_operatorsettings_simple(app, struct_)
            % simple version with no status change
            app.operator_settings = struct_;
            app.OperatorsettingsButton.Enable =  'on';
            app.operator_settings_table = create_table_from_structure(app.operator_settings);
            app.Update_Table();
            app.operator_settings_done   = 1      ; 
            
            app.Save_data_win_wait       = 0      ;  

        end
        end
       
    methods (Access = private)
        
 function  Update_Table(app)
     
switch(app.VIEWDropDown.Value)

case('Operator Settings')
app.UITable.Data            =      app.operator_settings_table;
app.UITable.ColumnName      =      app.operator_settings_table.Properties.VariableNames;
app.UITable.FontSize        =      12;
    case('Proc Options')
app.UITable.Data            =      app.proc_options_table;
app.UITable.ColumnName      =      app.proc_options_table.Properties.VariableNames;
app.UITable.FontSize        =      12;
    case('Plot Options')
app.UITable.Data            =      app.plot_options_table;
app.UITable.ColumnName      =      app.plot_options_table.Properties.VariableNames;
app.UITable.FontSize        =      10;  

case('Test_samples')
app.UITable.Data           =       app.test_settings_sampling_table;      
app.UITable.ColumnName     =       app.test_settings_sampling_table.Properties.VariableNames;
app.UITable.FontSize       =       12;

case('Test_Impedence')
app.UITable.Data           =       app.test_settings_Impedence_table;      
app.UITable.ColumnName     =       app.test_settings_Impedence_table.Properties.VariableNames;
app.UITable.FontSize       =       12;
    
case('Test_Toneburst')    
app.UITable.Data           =       app.test_settings_Toneburst_table;      
app.UITable.ColumnName     =       app.test_settings_Toneburst_table     .Properties.VariableNames;
app.UITable.FontSize       =       12;
    
case('Test_Instrument')
app.UITable.Data           =       app.test_settings_Instrument_table;      
app.UITable.ColumnName     =       app.test_settings_Instrument_table.Properties.VariableNames;
app.UITable.FontSize       =       12;
    
case('Test_Model')    

app.UITable.Data           =       app.test_settings_Model_table;      
app.UITable.ColumnName     =       app.test_settings_Model_table.Properties.VariableNames;
app.UITable.FontSize       =       12;

end %switch(app.VIEWDropDown.Value)

app.USER_NAMELabel.Text     = app.operator_settings.Name;
app.LOCATION_NAMELabel.Text = app.operator_settings.Site_name;

 end
        
        function Update_Status(app)
          
            switch (app.current_status)

                case(0)
                app.OSSetLamp.Color               = [1 0 0];
                app.TSSetLamp.Color               = [1 0 0];
                app.SettingsDoneLamp.Color        = [1 0 0];
                app.TestLoadedLamp.Color          = [1 0 0];
                app.TestProcessedLamp.Color       = [1 0 0];
                app.DataProcessedLamp.Color       = [1 0 0];         
                app.ResultsLamp.Color             = [1 0 0];
               
                % BUTTONS STATUS
                app.RunNewTestButton.Enable             =  'off';
                app.LoadExistingDataButton.Enable       =  'off';
                app.ViewOperatorsettingsButton.Enable =  'off';
                app.ViewTestsettingsButton.Enable      =  'off';
                
                app.ViewProcessingOptionsButton.Enable   =  'on';
                app.ChangeProcessingOptionsButton.Enable =  'on';
                app.ProcessDataButton.Enable             =  'off';
                app.ClearProcessedDataButton.Enable      =  'off';
                app. ClearCurrentDataButton.Enable      =  'off';
                app.ShowReportButton.Enable             =  'off';
                app.SaveProcessedDataButton.Enable      =  'off';  


                app.TestSettingsButton.Enable     =  'on';
                app.OperatorsettingsButton.Enable =  'on';


                case(0.25)% Operator settings done but not test settings
                app.OSSetLamp.Color               = [0 1 0];
                app.TSSetLamp.Color               = [1 0 0];
                app.SettingsDoneLamp.Color        = [1.00,0.41,0.16];
                app.TestLoadedLamp.Color          = [1 0 0];
                app.TestProcessedLamp.Color       = [1 0 0];
                app.DataProcessedLamp.Color       = [1 0 0];
                app.ResultsLamp.Color             = [1 0 0];
                                                
                % BUTTONS STATUS
                app.RunNewTestButton.Enable               =  'off';
                app.LoadExistingDataButton.Enable         =  'off';
                app.ViewOperatorsettingsButton.Enable   =  'off';
                app.ViewTestsettingsButton.Enable       =  'off';
                app.ViewProcessingOptionsButton.Enable   =  'on';
                app.ChangeProcessingOptionsButton.Enable =  'on';
                app.ProcessDataButton.Enable             =  'off';
                app.ClearProcessedDataButton.Enable      =  'off';
                app.ShowReportButton.Enable              =  'off';
                app. ClearCurrentDataButton.Enable       =  'off';
                app.OperatorsettingsButton.Enable        =  'off';
                app.SaveProcessedDataButton.Enable      =  'off';  


                case(0.75)% Test settings done but not operator settings
                app.OSSetLamp.Color               = [1,0,0];  
                app.TSSetLamp.Color               = [0 1 0];
                app.SettingsDoneLamp.Color        = [1.00,0.41,0.16];
                app.TestLoadedLamp.Color          = [1 0 0];
                app.TestProcessedLamp.Color       = [1 0 0];
                app.DataProcessedLamp.Color       = [1 0 0];
                app.ResultsLamp.Color             = [1 0 0];

                % BUTTONS STATUS
                app.RunNewTestButton.Enable         =  'off';
                app.LoadExistingDataButton.Enable   =  'off';
                app.ViewOperatorsettingsButton.Enable   =  'off';
                app.ViewTestsettingsButton.Enable =  'off';
                app.ViewProcessingOptionsButton.Enable   =  'on';
                app.ChangeProcessingOptionsButton.Enable =  'on';
                app.ProcessDataButton.Enable       =  'off';
                app.ClearProcessedDataButton.Enable      =  'off';
                app.ShowReportButton.Enable      =  'off';
                app. ClearCurrentDataButton.Enable     =  'off';   
                app.TestSettingsButton.Enable            =  'off';
                app.SaveProcessedDataButton.Enable      =  'off';  


                case(1)% SETTINGS
                app.OSSetLamp.Color               = [0 1 0];
                app.TSSetLamp.Color               = [0 1 0];
                app.SettingsDoneLamp.Color        = [0,1,0];                    
                app.TestLoadedLamp.Color          = [1 0 0];
                app.TestProcessedLamp.Color       = [1 0 0];
                app.DataProcessedLamp.Color       = [1 0 0];
                app.ResultsLamp.Color             = [1 0 0];

                % BUTTONS STATUS
                app.RunNewTestButton.Enable         =  'on';
                app.LoadExistingDataButton.Enable   =  'on';
                app.ViewOperatorsettingsButton.Enable   =  'off';
                app.ViewTestsettingsButton.Enable =  'off';
                app.ViewProcessingOptionsButton.Enable   =  'on';
                app.ChangeProcessingOptionsButton.Enable =  'on';
                app.ProcessDataButton.Enable       =  'off';
                app.ClearProcessedDataButton.Enable      =  'off';
                app.test_data =   [];
                
                app.CurrentdatasetTextArea.Value  =  '';
                app.TestDateandTimeTextArea.Value =  '';
                app.SiteNameTextArea.Value        =  '';
                app.OperatorTextArea.Value        =  '';
                app.NotesEditField.Value          =  '';
                app.ShowReportButton.Enable       =  'off';
                app. ClearCurrentDataButton.Enable     =  'off';  


                app.TestSettingsButton.Enable            =  'off';
                app.OperatorsettingsButton.Enable        =  'off';
                app.SaveProcessedDataButton.Enable      =  'off';  


                case(1.5)% DATA  -  But error in data
                app.OSSetLamp.Color               = [0 1 0];
                app.TSSetLamp.Color               = [0 1 0];
                app.SettingsDoneLamp.Color        = [0,1,0];
                app.TestLoadedLamp.Color          = [1.00,0.41,0.16];
                app.TestProcessedLamp.Color       = [1 0 0];
                app.DataProcessedLamp.Color       = [1 0 0];
                app.ResultsLamp.Color             = [1 0 0];
                % BUTTONS STATUS
                app.RunNewTestButton.Enable         =  'on';
                app.LoadExistingDataButton.Enable   =  'on';
                app.ViewOperatorsettingsButton.Enable   =  'on';
                app.ViewTestsettingsButton.Enable =  'on';
                app.ViewProcessingOptionsButton.Enable   =  'on';
                app.ChangeProcessingOptionsButton.Enable =  'on';
                app.ProcessDataButton.Enable       =  'on';
                app.ClearProcessedDataButton.Enable      =  'off';
                app.ShowReportButton.Enable      =  'off';
                app. ClearCurrentDataButton.Enable     =  'on'; 
                app.SaveProcessedDataButton.Enable      =  'off';  


                case(2)% DATA
                app.OSSetLamp.Color               = [0 1 0];
                app.TSSetLamp.Color               = [0 1 0];
                app.SettingsDoneLamp.Color        = [0,1,0];
                app.TestLoadedLamp.Color          = [0 1 0];
                app.TestProcessedLamp.Color       = [1 0 0];
                app.DataProcessedLamp.Color       = [1 0 0];
                app.ResultsLamp.Color             = [1 0 0];
                % BUTTONS STATUS
                app.RunNewTestButton.Enable         =  'on';
                app.LoadExistingDataButton.Enable   =  'on';
                app.ViewOperatorsettingsButton.Enable   =  'on';
                app.ViewTestsettingsButton.Enable =  'on';
                %   clear any  Processed data at this point
                app. ClearCurrentDataButton.Enable     =  'on';
                app.ViewProcessingOptionsButton.Enable   =  'on';
                app.ChangeProcessingOptionsButton.Enable =  'on';
                app.ProcessDataButton.Enable       =  'on';
                app.ClearProcessedDataButton.Enable      =  'off';
                app.ShowReportButton.Enable      =  'off';
                 app.SaveProcessedDataButton.Enable      =  'off'; 


                case(3)% Process DATA
                app.OSSetLamp.Color               = [0 1 0];
                app.TSSetLamp.Color               = [0 1 0];
                app.SettingsDoneLamp.Color        = [0,1,0];        
                app.TestLoadedLamp.Color          = [0 1 0];
                app.TestProcessedLamp.Color       = [0 1 0];
                app.DataProcessedLamp.Color       = [0 1 0];
                app.ResultsLamp.Color             = [1 0 0]; 
                % BUTTONS STATUS
                app.RunNewTestButton.Enable              =  'off';
                app.LoadExistingDataButton.Enable        =  'off';
                app.ViewOperatorsettingsButton.Enable    =  'off';
                app.ViewTestsettingsButton.Enable        =  'off';
                app. ClearCurrentDataButton.Enable       =  'off';
                app.ViewProcessingOptionsButton.Enable   =  'on';
                app.ChangeProcessingOptionsButton.Enable =  'off';
                app.ProcessDataButton.Enable             =  'off';
                app.SaveProcessedDataButton.Enable      =  'on';  

                app.ClearProcessedDataButton.Enable      =  'on';
                app.ShowReportButton.Enable              =  'on';
                
                
                case(4) % DISPLAYED RESULTS+ REPORT -  ready to reset and take more data
                app.OSSetLamp.Color               = [0 1 0];
                app.TSSetLamp.Color               = [0 1 0];
                app.SettingsDoneLamp.Color        = [0,1,0];
                app.TestLoadedLamp.Color          = [0 1 0];                
                app.TestProcessedLamp.Color       = [0 1 0];
                app.DataProcessedLamp.Color       = [0 1 0];
                app.ResultsLamp.Color             = [0 1 0];
                % BUTTONS STATUS
                app.RunNewTestButton.Enable         =  'off';
                app.LoadExistingDataButton.Enable   =  'off';
                app.ViewOperatorsettingsButton.Enable   =  'off';
                app.ViewTestsettingsButton.Enable =  'off';
                app. ClearCurrentDataButton.Enable     =  'off';
                app.ViewProcessingOptionsButton.Enable   =  'on';
                app.ChangeProcessingOptionsButton.Enable =  'off';
                app.ProcessDataButton.Enable       =  'off';
                app.ClearProcessedDataButton.Enable      =  'on';
                app.ShowReportButton.Enable      =  'on';

            end  %switch (app.current_status)
            
          %  Buttons
          % lights

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % slab_root 
            % mkdir(settings_root)
            % mkdir([settings_root,'\Operator_files'])
            % fprintf('hello\ntest\n') 

            %  **********************************************************
            %  The \SETTINGS_FILES  are in the  roaming area -   first check if that directory exists there and if not create it and make sure it has the Setting files in it 
            %  define the base area ABOVE
            %  have a message box showing what is done
            
            if exist([app.settings_root,'\SETTINGS_FILES']) ~= 7  
            mkdir(app.settings_root)
            end
            
            if exist([app.settings_root,'\SETTINGS_FILES\Operator_files']) ~= 7
            mkdir([app.settings_root,'\SETTINGS_FILES\Operator_files'])
            end %if([settings_root,'\Operator_files'])
            
            if exist([getenv('APPDATA'),'\SETTINGS_FILES\proc_options_.dat']) ~=2
            copyfile([pwd,'\SETTINGS_FILES\proc_options_.dat'],[app.settings_root,'\SETTINGS_FILES']); 
            end

            if exist([getenv('APPDATA'),'\SETTINGS_FILES\Test_Settings.dat']) ~=2
            copyfile([pwd,'\SETTINGS_FILES\Test_Settings.dat'],[app.settings_root,'\SETTINGS_FILES']); 
            end 

            if exist([getenv('APPDATA'),'\SETTINGS_FILES\plot_options_.dat']) ~=2
            copyfile([pwd,'\SETTINGS_FILES\plot_options_.dat'],[app.settings_root,'\SETTINGS_FILES']); 
            end
 

            if exist([app.settings_root,'\Operator_files\Operator_Settings.dat']) ~= 2
            copyfile([pwd,'\SETTINGS_FILES\Operator_files\Operator_Settings.dat'],[app.settings_root,'\SETTINGS_FILES\Operator_files']);
            end %if exist([app.settings_root,'\Operator_files\Operator_Settings.dat']) ~= 2
 
            %  ********************************************************** 
            %  The \SLAB_data   is in the  [getenv('systemDrive'),getenv('homePath'),\SLAB_data ]  area-  first check it exist  


            if exist([app.slab_root,'\SLAB_data']) ~= 7
            mkdir([app.slab_root ,'\SLAB_data']) ; 
            end %if exist([getenv('systemDrive'),getenv('homePath'),'\SLAB_data']) ~= 7
            %  ********************************************************** 
             
            app.default_options =  load_structure_from_file([pwd,app.location_of_default_options],app.default_option_fields);
            
            app.proc_options        =   load_structure_from_file([app.settings_root,app.default_options.proc_options_fname],...
                                                          app.default_options.proc_expected_fields);

            app.plot_options        =   load_structure_from_file([app.settings_root,app.default_options.plot_options_fname],...
                                                         app.default_options.plot_expected_fields);

            app.test_settings       =   load_structure_from_file([app.settings_root,app.default_options.Test_Settings_fname],...
                                                         app.default_options.Test_expected_fields);

            app.operator_settings   =   load_structure_from_file([app.settings_root,app.default_options.Operator_Settings_fname],...
                                                         app.default_options.Operator_expected_fields);

            app.operator_settings_table   = create_table_from_structure(app.operator_settings);
            
            % app.test_settings_table       = create_table_from_structure(app.test_settings); 
            app.proc_options_table   =create_table_from_structure(app.proc_options);
            app.plot_options_table   =create_table_from_structure(app.plot_options);
            
            app.test_settings_sampling_table      =  create_table_from_structure(app.test_settings.Sampling);
            app.test_settings_Impedence_table     =  create_table_from_structure(app.test_settings.Impedence);
            app.test_settings_Toneburst_table     =  create_table_from_structure(app.test_settings.Toneburst);
            app.test_settings_Instrument_table    =  create_table_from_structure(app.test_settings.Instrument_attributes);
            app.test_settings_Model_table         =  create_table_from_structure(app.test_settings.Model_data);
                        
            app.USER_NAMELabel.Text = app.operator_settings.Name;
            app.LOCATION_NAMELabel.Text = app.operator_settings.Site_name;
                      
                     
            app.MMDistandceEditField.Value   =  num2str(app.plot_options.plot_distance);
            app.TxEditField.Value            =  num2str(app.plot_options.tx_mode_princ);
            app.RxEditField.Value            =  num2str(app.plot_options.rx_mode_princ); 


            app.Update_Table();

                app.RunNewTestButton.Enable             =  'off';
                app.LoadExistingDataButton.Enable       =  'off';
                app.ViewOperatorsettingsButton.Enable =  'off';
                app.ViewTestsettingsButton.Enable     =  'off';
                app. ClearCurrentDataButton.Enable     =  'off';

                app.ViewProcessingOptionsButton.Enable   =  'off';
                app.ChangeProcessingOptionsButton.Enable =  'off';
                app.ProcessDataButton.Enable             =  'off';
                app.ClearProcessedDataButton.Enable      =  'off';


        end

        % Button pushed function: OperatorsettingsButton
        function OperatorsettingsButtonPushed(app, event)
            app.OperatorsettingsButton.Enable =  'off';
            app.operator_sub_inst = operator_sub(app, app.operator_settings, app.default_options);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            %delete(app.operator_sub_inst)
        end

        % Value changed function: VIEWDropDown
        function VIEWDropDownValueChanged(app, event)
            value = app.VIEWDropDown.Value;
            app.Update_Table();

        end

        % Drop down opening function: VIEWDropDown
        function VIEWDropDownOpening(app, event)
            
        end

        % Button pushed function: TestSettingsButton
        function TestSettingsButtonPushed(app, event)
            app.TestSettingsButton.Enable =  'off';
            app.TestSettings_sub_inst = test_set_sub(app, app.test_settings, app.default_options);
        end

        % Button pushed function: LoadExistingDataButton
        function LoadExistingDataButtonPushed(app, event)

%cd([app.default_options.drive_letter_root , app.default_options.raw_data_file_path])

answer__ = questdlg('Use original operator/test settings or the current ones?','Choose', 'ORIGINAL','CURRENT','ORIGINAL');

dummy_ = app.default_options.raw_data_file_path  ;
[file_,path_, ok_] = uigetfile([app.slab_root ,dummy_ , '\*.mat']);

if ok_ ==1
file_with_path  = [path_,file_];

dummy__ = load(file_with_path); 

if isfield(dummy__,'test_data')
app.test_data = dummy__.test_data ;    

if length(fields(app.test_data)) == 5  && isfield(app.test_data,'raw_data')&& isfield(app.test_data,'fixed_Test_Settings') &&...
isfield(app.test_data,'fixed_Operator_Settings')&& isfield(app.test_data,'file_with_path') && isfield(app.test_data,'date_time')  
%file has the correct number of field and they have the correct names
else

%file does not have the correct number of field and/or they do not have the correct names
end %if length(fields(test_data)) == 4  && isfield(test_data,'raw_data')  
else
msgbox('The file is not the correct format needs to have a field') 
end %if isfield(dummy__,'app.test_data')

switch(answer__)
    case('ORIGINAL')
% dont do anything        
    case('CURRENT')
app.test_data.fixed_Operator_Settings   =  app.operator_settings  ;
app.test_data.fixed_Test_Settings       =  app.test_settings      ;
end %switch(answer__)


app.current_test_folder = path_;
app.CurrentdatasetTextArea.Value = app.test_data.file_with_path;
app.TestDateandTimeTextArea.Value = datestr(datetime(app.test_data.date_time));
app.SiteNameTextArea.Value =  app.test_data.fixed_Operator_Settings.Site_name;
app.OperatorTextArea.Value =app.test_data.fixed_Operator_Settings.Name;
app.NotesEditField.Value  =  app.test_data.fixed_Operator_Settings.Notes;
app.current_status = 2;
app.Update_Status();

end % if ok_ ==1

        end

        % Button pushed function: RunNewTestButton
        function RunNewTestButtonPushed(app, event)
                       
            [raw_data,err] =  run_alva_DAQ(app.test_settings)                               ;        
            
            if err==0
            %%%% allow changes to operator set    tings here   %%%%%%     
            % run the Save_data_win app               
                        
            clock_temp = fix(clock)  ;
            save_answer = questdlg('Would you like to Save the test data?', ...
	        'Options',...
            'Save as is', 'Change OS and Save','Dont Save','Save as is');   
            
            if strcmp(save_answer,'Change OS and Save' )
            
            app.Save_data_win_wait = 1; 
           
            Handle_ =  Save_data_win(app, app.operator_settings, app.default_options);
            waitfor(Handle_)
            
            end % if strcmp(answer,'Change OS and Save' )
            %   wait atthis point until the  window opened and closed    

            switch (save_answer)
                case{'Save as is','Change OS and Save'}
            app.test_data.raw_data                 = raw_data                           ;
            app.test_data.fixed_Test_Settings      = app.test_settings                  ; 
            app.test_data.fixed_Operator_Settings  = app.operator_settings              ;
            app.test_data.date_time                = clock_temp                         ;
            
            app.test_data =   save_in_file_structure (app.slab_root, app.test_data,app.default_options); 
            app.current_test_folder = app.test_data.file_with_path(1:max(find(app.test_data.file_with_path=='\')));
            app.CurrentdatasetTextArea.Value = app.test_data.file_with_path;
            app.TestDateandTimeTextArea.Value = datestr(datetime(app.test_data.date_time));
            app.SiteNameTextArea.Value =  app.test_data.fixed_Operator_Settings.Site_name;
            app.OperatorTextArea.Value =app.test_data.fixed_Operator_Settings.Name;
            app.NotesEditField.Value  =  app.test_data.fixed_Operator_Settings.Notes;
            app.current_status = 2;
            app.Update_Status();
            
                case('Dont Save')
            %  no need to do anything
            % nothing saves and no change in status
            end % switch (save_answer)
           
            end % if err==0
           
        end

        % Button pushed function: ViewOperatorsettingsButton
        function ViewOperatorsettingsButtonPushed(app, event)
            View_operator(app, app.test_data.fixed_Operator_Settings)
        end

        % Button pushed function: ViewTestsettingsButton
        function ViewTestsettingsButtonPushed(app, event)
            view_test(app, app.test_data.fixed_Test_Settings)
        end

        % Button pushed function: ViewProcessingOptionsButton
        function ViewProcessingOptionsButtonPushed(app, event)
            view_proc(app, app.proc_options)            
        end

        % Button pushed function: ChangeProcessingOptionsButton
        function ChangeProcessingOptionsButtonPushed(app, event)
            set_proc(app, app.proc_options, app.default_options);
        end

        % Button pushed function: ProcessDataButton
        function ProcessDataButtonPushed(app, event)
          [app.rail_tester,loaded_fe_file_ok ] =  create_rail_tester_structure(app.default_options,app.test_data,app.proc_options);
                    
          if loaded_fe_file_ok~= 1
          msgbox('warning issue with fe file loading')
          end %if loaded_fe_file_ok~= 1
          app.rail_tester                                =  fn_process_rail_data(app.rail_tester, app.proc_options);
          app.current_status = 3;
          app.Update_Status();
        end

        % Button pushed function: ClearProcessedDataButton
        function ClearProcessedDataButtonPushed(app, event)
            msgbox('Processed data cleared') % written in the app for case (2) 
            app.current_status = 2   ;
            app.Update_Status()      ;            
        end

        % Button pushed function: ClearCurrentDataButton
        function ClearCurrentDataButtonPushed(app, event)
            app.current_test_folder = '';
            app.current_status = 1   ;
            app.Update_Status()      ;
            
        end

        % Button pushed function: ShowReportButton
        function ShowReportButtonPushed(app, event)
            app.current_status = 4;
            app.Update_Status();
        end

        % Button pushed function: ShowmodeMapButton
        function ShowmodeMapButtonPushed(app, event)
        
        [grid_data] = fn_get_grid_data(app.rail_tester , app.plot_options); 
        %fn_plot_grid_data(grid_data,app.plot_options,app.operator_settings.feature_distance_m);    
        fn_plot_grid_data(grid_data,app.plot_options,app.plot_options.plot_distance);      
        end

        % Button pushed function: SelectedModePlotsButton
        function SelectedModePlotsButtonPushed(app, event)
            fn_plot_cw_result(app.rail_tester, app.plot_options);
        end

        % Button pushed function: ShowDispersionButton
        function ShowDispersionButtonPushed(app, event)
            showdispersion(app.plot_options,app.rail_tester);
        end

        % Button pushed function: ShowMeshButton
        function ShowMeshButtonPushed(app, event)
            show_mesh(app.rail_tester);
        end

        % Button pushed function: AcceptValuesButton
        function AcceptValuesButtonPushed(app, event)
            app.current_status = 1;
            app.Update_Status();
            app.TabGroup.SelectedTab = app.TestDataTab;
            % This line was entered by MJE on 18/1/24 to test the old git
            %  app.TestDataTabButtonDown2(app, event)  ---   doest work
            %  (private function)
        end

        % Button pushed function: ViewSetPlotOptionsButton
        function ViewSetPlotOptionsButtonPushed(app, event)
           Set_plot(app, app.plot_options,app.default_options)  

        end

        % Button pushed function: ShowPrincipalModeButton
        function ShowPrincipalModeButtonPushed(app, event)
            temp_options = app.plot_options; 
            temp_options.tx_modes = temp_options.tx_mode_princ;     
            temp_options.rx_modes = temp_options.rx_mode_princ;   
            fn_plot_cw_result(app.rail_tester, temp_options);
            
            %[x,y] = ginput(1);
            % keyboard

        end

        % Value changed function: MMDistandceEditField
        function MMDistandceEditFieldValueChanged(app, event)
            value = app.MMDistandceEditField.Value;
             
        end

        % Value changed function: TxEditField
        function TxEditFieldValueChanged(app, event)
            value = app.TxEditField.Value;
            
        end

        % Value changed function: RxEditField
        function RxEditFieldValueChanged(app, event)
            value = app.RxEditField.Value;
            
        end

        % Button pushed function: SetMMButton
        function SetMMButtonPushed(app, event)
            %app.MMDistandceEditField.Value   =  app.plot_options.plot_distance;
            if ~isempty(str2num(app.MMDistandceEditField.Value))
            pass_val =  str2num(app.MMDistandceEditField.Value); 
            %  check the value here
            app.plot_options.plot_distance = pass_val;    
           
            else
            msgbox('value should be a number- Not set')
            end 

        end

        % Button pushed function: SetPMButton
        function SetPMButtonPushed(app, event)
             
             if  ~isempty(str2num(app.TxEditField.Value))
             pass_value = str2num(app.TxEditField.Value);
             %  check the value here 
             app.plot_options.tx_mode_princ = pass_value;
             else
             msgbox('Tx value should be a number- Not set')
             end

             if  ~isempty(str2num(app.RxEditField.Value))
             pass_value = str2num(app.RxEditField.Value);
             %  check the value here 
             app.plot_options.rx_mode_princ = pass_value;
             else
             msgbox('Rx value should be a number- Not set')
             end

        end

        % Button pushed function: allowvaluechangesButton
        function allowvaluechangesButtonPushed(app, event)
            app.current_status = 0;
            app.Update_Status();

        end

        % Button pushed function: SaveProcessedDataButton
        function SaveProcessedDataButtonPushed(app, event)

          save_processed_data(app.test_data ,app.rail_tester,app.current_test_folder , app.proc_options)
          %app.Update_Status();   
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.UITable = uitable(app.SettingsTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.OperatorsettingsButton = uibutton(app.SettingsTab, 'push');
            app.OperatorsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @OperatorsettingsButtonPushed, true);
            app.OperatorsettingsButton.Text = 'Operator settings';
            app.TestSettingsButton = uibutton(app.SettingsTab, 'push');
            app.TestSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @TestSettingsButtonPushed, true);
            app.TestSettingsButton.Text = 'Test Settings';
            app.VIEWDropDownLabel = uilabel(app.SettingsTab);
            app.VIEWDropDownLabel.Text = 'VIEW:';
            app.VIEWDropDown = uidropdown(app.SettingsTab);
            app.VIEWDropDown.Items = {'Operator Settings', 'Proc Options', 'Plot Options', 'Test_samples', 'Test_Impedence', 'Test_Toneburst', 'Test_Instrument', 'Test_Model'};
            app.VIEWDropDown.DropDownOpeningFcn = createCallbackFcn(app, @VIEWDropDownOpening, true);
            app.VIEWDropDown.ValueChangedFcn = createCallbackFcn(app, @VIEWDropDownValueChanged, true);
            app.VIEWDropDown.Value = 'Operator Settings';
            app.SETLabel = uilabel(app.SettingsTab);
            app.SETLabel.Text = 'SET:';
            app.OSSetLampLabel = uilabel(app.SettingsTab);
            app.OSSetLampLabel.Text = 'OS Set';
            app.OSSetLamp = uilamp(app.SettingsTab);
            app.TSSetLampLabel = uilabel(app.SettingsTab);
            app.TSSetLampLabel.Text = 'TS Set';
            app.TSSetLamp = uilamp(app.SettingsTab);
            app.AcceptValuesButton = uibutton(app.SettingsTab, 'push');
            app.AcceptValuesButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptValuesButtonPushed, true);
            app.AcceptValuesButton.Text = 'Accept Values';
            app.allowvaluechangesButton = uibutton(app.SettingsTab, 'push');
            app.allowvaluechangesButton.ButtonPushedFcn = createCallbackFcn(app, @allowvaluechangesButtonPushed, true);
            app.allowvaluechangesButton.Text = 'allow value changes';
            app.TestDataTab = uitab(app.TabGroup);
            app.TestDataTab.Title = 'Test /Data';
            app.LoadExistingDataButton = uibutton(app.TestDataTab, 'push');
            app.LoadExistingDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExistingDataButtonPushed, true);
            app.LoadExistingDataButton.Text = 'Load Existing Data';
            app.RunNewTestButton = uibutton(app.TestDataTab, 'push');
            app.RunNewTestButton.ButtonPushedFcn = createCallbackFcn(app, @RunNewTestButtonPushed, true);
            app.RunNewTestButton.Text = 'Run New Test';
            app.ViewOperatorsettingsButton = uibutton(app.TestDataTab, 'push');
            app.ViewOperatorsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewOperatorsettingsButtonPushed, true);
            app.ViewOperatorsettingsButton.Text = 'View Operator settings';
            app.ViewTestsettingsButton = uibutton(app.TestDataTab, 'push');
            app.ViewTestsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewTestsettingsButtonPushed, true);
            app.ViewTestsettingsButton.Text = 'View Test settings';
            app.CurrentdatasetTextAreaLabel = uilabel(app.TestDataTab);
            app.CurrentdatasetTextAreaLabel.Text = 'Current data set';
            app.CurrentdatasetTextArea = uitextarea(app.TestDataTab);
            app.TestDateandTimeTextAreaLabel = uilabel(app.TestDataTab);
            app.TestDateandTimeTextAreaLabel.Text = 'Test Date and Time';
            app.TestDateandTimeTextArea = uitextarea(app.TestDataTab);
            app.SiteNameTextAreaLabel = uilabel(app.TestDataTab);
            app.SiteNameTextAreaLabel.Text = 'Site Name';
            app.SiteNameTextArea = uitextarea(app.TestDataTab);
            app.OperatorTextAreaLabel = uilabel(app.TestDataTab);
            app.OperatorTextAreaLabel.Text = 'Operator';
            app.OperatorTextArea = uitextarea(app.TestDataTab);
            app.ClearCurrentDataButton = uibutton(app.TestDataTab, 'push');
            app.ClearCurrentDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearCurrentDataButtonPushed, true);
            app.ClearCurrentDataButton.Text = 'Clear Current Data';
            app.NotesEditFieldLabel = uilabel(app.TestDataTab);
            app.NotesEditFieldLabel.Text = 'Notes';
            app.NotesEditField = uieditfield(app.TestDataTab, 'text');
            app.ProcessingTab = uitab(app.TabGroup);
            app.ProcessingTab.Title = 'Processing';
            app.ViewProcessingOptionsButton = uibutton(app.ProcessingTab, 'push');
            app.ViewProcessingOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewProcessingOptionsButtonPushed, true);
            app.ViewProcessingOptionsButton.Text = 'View Processing Options';
            app.ProcessDataButton = uibutton(app.ProcessingTab, 'push');
            app.ProcessDataButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessDataButtonPushed, true);
            app.ProcessDataButton.Text = 'Process Data';
            app.ClearProcessedDataButton = uibutton(app.ProcessingTab, 'push');
            app.ClearProcessedDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearProcessedDataButtonPushed, true);
            app.ClearProcessedDataButton.Text = 'Clear Processed Data';
            app.DataProcessedLampLabel = uilabel(app.ProcessingTab);
            app.DataProcessedLampLabel.Text = 'Data Processed ';
            app.DataProcessedLamp = uilamp(app.ProcessingTab);
            app.ChangeProcessingOptionsButton = uibutton(app.ProcessingTab, 'push');
            app.ChangeProcessingOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeProcessingOptionsButtonPushed, true);
            app.ChangeProcessingOptionsButton.Text = 'Change Processing Options';
            app.SaveProcessedDataButton = uibutton(app.ProcessingTab, 'push');
            app.SaveProcessedDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProcessedDataButtonPushed, true);
            app.SaveProcessedDataButton.Text = 'Save Processed Data';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.ViewSetPlotOptionsButton = uibutton(app.ResultsTab, 'push');
            app.ViewSetPlotOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewSetPlotOptionsButtonPushed, true);
            app.ViewSetPlotOptionsButton.Text = {'View /Set '; 'Plot Options'};
            app.ShowmodeMapButton = uibutton(app.ResultsTab, 'push');
            app.ShowmodeMapButton.ButtonPushedFcn = createCallbackFcn(app, @ShowmodeMapButtonPushed, true);
            app.ShowmodeMapButton.Text = 'Show mode Map';
            app.ShowMeshButton = uibutton(app.ResultsTab, 'push');
            app.ShowMeshButton.ButtonPushedFcn = createCallbackFcn(app, @ShowMeshButtonPushed, true);
            app.ShowMeshButton.Text = 'Show Mesh';
            app.ShowPrincipalModeButton = uibutton(app.ResultsTab, 'push');
            app.ShowPrincipalModeButton.ButtonPushedFcn = createCallbackFcn(app, @ShowPrincipalModeButtonPushed, true);
            app.ShowPrincipalModeButton.Text = 'Show Principal Mode';
            app.ShowDispersionButton = uibutton(app.ResultsTab, 'push');
            app.ShowDispersionButton.ButtonPushedFcn = createCallbackFcn(app, @ShowDispersionButtonPushed, true);
            app.ShowDispersionButton.Text = 'Show Dispersion';
            app.ProcessingNotesTextAreaLabel = uilabel(app.ResultsTab);
            app.ProcessingNotesTextAreaLabel.Text = 'Processing Notes';
            app.ProcessingNotesTextArea = uitextarea(app.ResultsTab);
            app.ShowReportButton = uibutton(app.ResultsTab, 'push');
            app.ShowReportButton.ButtonPushedFcn = createCallbackFcn(app, @ShowReportButtonPushed, true);
            app.ShowReportButton.Text = 'Show Report';
            app.SelectedModePlotsButton = uibutton(app.ResultsTab, 'push');
            app.SelectedModePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectedModePlotsButtonPushed, true);
            app.SelectedModePlotsButton.Text = 'Selected Mode Plots';
            app.SaveReportButton = uibutton(app.ResultsTab, 'push');
            app.SaveReportButton.Text = 'Save Report';
            app.MMDistandceEditFieldLabel = uilabel(app.ResultsTab);
            app.MMDistandceEditFieldLabel.Text = 'MM Distandce';
            app.MMDistandceEditField = uieditfield(app.ResultsTab, 'text');
            app.MMDistandceEditField.ValueChangedFcn = createCallbackFcn(app, @MMDistandceEditFieldValueChanged, true);
            app.TxEditFieldLabel = uilabel(app.ResultsTab);
            app.TxEditFieldLabel.Text = 'Tx';
            app.TxEditField = uieditfield(app.ResultsTab, 'text');
            app.TxEditField.ValueChangedFcn = createCallbackFcn(app, @TxEditFieldValueChanged, true);
            app.RxEditFieldLabel = uilabel(app.ResultsTab);
            app.RxEditFieldLabel.Text = 'Rx';
            app.RxEditField = uieditfield(app.ResultsTab, 'text');
            app.RxEditField.ValueChangedFcn = createCallbackFcn(app, @RxEditFieldValueChanged, true);
            app.SetMMButton = uibutton(app.ResultsTab, 'push');
            app.SetMMButton.ButtonPushedFcn = createCallbackFcn(app, @SetMMButtonPushed, true);
            app.SetMMButton.Text = 'Set MM';
            app.SetPMButton = uibutton(app.ResultsTab, 'push');
            app.SetPMButton.ButtonPushedFcn = createCallbackFcn(app, @SetPMButtonPushed, true);
            app.SetPMButton.Text = 'Set PM';
            app.IncludeProcessingnotesinreoprtCheckBox = uicheckbox(app.ResultsTab);
            app.IncludeProcessingnotesinreoprtCheckBox.Text = 'Include Processing notes in reoprt';
            app.IncludemodemapCheckBox = uicheckbox(app.ResultsTab);
            app.IncludemodemapCheckBox.Text = 'Include mode map';
            app.IncludePrincipalmodeCheckBox = uicheckbox(app.ResultsTab);
            app.IncludePrincipalmodeCheckBox.Text = 'Include Principal mode';
            app.includemodeplotsCheckBox = uicheckbox(app.ResultsTab);
            app.includemodeplotsCheckBox.Text = 'include mode plots';
            app.includeDispcurveCheckBox = uicheckbox(app.ResultsTab);
            app.includeDispcurveCheckBox.Text = 'include Disp curve';
            app.includemeshCheckBox = uicheckbox(app.ResultsTab);
            app.includemeshCheckBox.Text = 'include mesh';
            app.includeOperatorSettingsCheckBox = uicheckbox(app.ResultsTab);
            app.includeOperatorSettingsCheckBox.Text = 'include Operator Settings';
            app.includeprocessingparametersCheckBox = uicheckbox(app.ResultsTab);
            app.includeprocessingparametersCheckBox.Text = 'include processing parameters';
            app.includeplotparametersCheckBox = uicheckbox(app.ResultsTab);
            app.includeplotparametersCheckBox.Text = 'include plot parameters';
            app.WIRETESTLabel = uilabel(app.UIFigure);
            app.WIRETESTLabel.Text = 'WIRE-TEST';
            app.TestLoadedLampLabel = uilabel(app.UIFigure);
            app.TestLoadedLampLabel.Text = 'Test Loaded';
            app.TestLoadedLamp = uilamp(app.UIFigure);
            app.TestProcessedLampLabel = uilabel(app.UIFigure);
            app.TestProcessedLampLabel.Text = 'Test Processed';
            app.TestProcessedLamp = uilamp(app.UIFigure);
            app.ResultsLampLabel = uilabel(app.UIFigure);
            app.ResultsLampLabel.Text = {'Results'; ''};
            app.ResultsLamp = uilamp(app.UIFigure);
            app.UserLabel = uilabel(app.UIFigure);
            app.UserLabel.Text = 'User';
            app.SettingsDoneLampLabel = uilabel(app.UIFigure);
            app.SettingsDoneLampLabel.Text = {'Settings'; 'Done'; ''};
            app.SettingsDoneLamp = uilamp(app.UIFigure);
            app.USER_NAMELabel = uilabel(app.UIFigure);
            app.USER_NAMELabel.Text = {'USER_NAME'; ''};
            app.SiteNameLabel = uilabel(app.UIFigure);
            app.SiteNameLabel.Text = 'Site Name';
            app.LOCATION_NAMELabel = uilabel(app.UIFigure);
            app.LOCATION_NAMELabel.Text = 'LOCATION_NAME';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WireProApp_BU

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end