classdef operator_sub < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SetcurrentasdefaultButton  matlab.ui.control.Button
        ExitwithoutvaluesButton    matlab.ui.control.Button
        ExitandSetValuesButton     matlab.ui.control.Button
        ReloadDefaultsButton       matlab.ui.control.Button
        OperatorSettingsLabel      matlab.ui.control.Label
        SaveFileButton             matlab.ui.control.Button
        OpenFileButton             matlab.ui.control.Button
        UpdateValueButton          matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        ValueEditField             matlab.ui.control.EditField
        ValueEditFieldLabel        matlab.ui.control.Label
        FieldNameDropDown          matlab.ui.control.DropDown
        FieldNameDropDownLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        % also need the location of the settings files          
        parent_app                 ;   
        default_options            ;   
        operator_settings          ;
        saving_root                ;  
        operator_settings_table    ;
    end
    
    methods (Access = private)
                
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, par_, struct_1, struct_2)
            app.parent_app = par_;
            app.operator_settings = struct_1;
            app.default_options = struct_2;
            
            app.saving_root = getenv('APPDATA');
            app.FieldNameDropDown.Items = fieldnames(app.operator_settings)';
            app.operator_settings_table = create_table_from_structure(app.operator_settings);
            app.UITable.Data = app.operator_settings_table;
            app.UITable.ColumnName = app.operator_settings_table.Properties.VariableNames;
            app.ValueEditField.Value = num2str(getfield(app.operator_settings,app.FieldNameDropDown.Value));
            
        end

        % Callback function
        function ShowValueButtonPushed(app, event)
            
            %msgbox(num2str(getfield(app.operator_settings,app.FieldNameDropDown.Value)))
            app.ValueEditField.Value = num2str(getfield(app.operator_settings,app.FieldNameDropDown.Value));
        end

        % Value changed function: ValueEditField
        function ValueEditFieldValueChanged(app, event)
            value = app.ValueEditField.Value;          

        end

        % Value changed function: FieldNameDropDown
        function FieldNameDropDownValueChanged(app, event)
            value = app.FieldNameDropDown.Value;
            app.ValueEditField.Value = num2str(getfield(app.operator_settings,app.FieldNameDropDown.Value));
        end

        % Button pushed function: UpdateValueButton
        function UpdateValueButtonPushed(app, event)
            % Get the field 
            % set the field 

            if ~isempty(str2num(app.ValueEditField.Value))
            pass_val =  str2num(app.ValueEditField.Value); 
            else
            pass_val = app.ValueEditField.Value;
            end

            [messge_ , check_ok] = Check_Operator_settings(app.FieldNameDropDown.Value,pass_val);
            

            if check_ok ==1
            app.operator_settings = setfield(app.operator_settings,num2str(app.FieldNameDropDown.Value), pass_val);
            %app.operator_settings = setfield(app.operator_settings,num2str(app.FieldNameDropDown.Value), app.ValueEditField.Value);
            app.operator_settings_table = create_table_from_structure(app.operator_settings);
            app.UITable.Data = app.operator_settings_table;
            else
            msgbox(messge_)    
            end %if check_ok ==1

        end

        % Button pushed function: ReloadDefaultsButton
        function ReloadDefaultsButtonPushed(app, event)
            % 
            % Operator_Settings,ok_] = load_structure_from_file([app.default_options.drive_letter_root,app.default_options.Operator_Settings_fname],
            % app.default_options.Operator_expected_fields)
           
           [app.operator_settings,ok_] = load_structure_from_file([pwd,app.default_options.Operator_Settings_fname],....
           app.default_options.Operator_expected_fields);
           if ok_~= 1 ; msgbox('Warning:: structure fields dont match defaults') ;end      
           % now reset the display boxes and table
           app.operator_settings_table = create_table_from_structure(app.operator_settings);
           app.UITable.Data = app.operator_settings_table;
           app.ValueEditField.Value = num2str(getfield(app.operator_settings,app.FieldNameDropDown.Value));

        end

        % Button pushed function: SaveFileButton
        function SaveFileButtonPushed(app, event)
            answer_ = inputdlg('Enter file name','Save Operator Settings file',[1,20]);
            
            if ~isempty(answer_)
            file_name_temp = [answer_{1},'_USER.dat'] ; 

            if exist([pwd,app.default_options.Operator_Settings_fname(1:max(find(app.default_options.Operator_Settings_fname=='\'))), file_name_temp])  ==2 
            choice_ = questdlg('Overwrite?', 'File Exists' , 'Yes' , 'No' , 'No');
            
            if strcmp(choice_,'Yes')
            writefile_ = 1;
            else writefile_ = 0; 
            end
            else
            writefile_ = 1;
            end
            if writefile_ == 1
            OK_ =   create_file_from_structure(app.operator_settings,file_name_temp,[app.saving_root,app.default_options.Operator_Settings_fname(1:max(find(app.default_options.Operator_Settings_fname=='\')))]);
            if ~OK_; msgbox('warning error flagged on save processes'); end

            end %if writefile_ == 1
            end %if ~isempty(answer_)

        end

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
            %  Bring up a selection box on the
             temp_ =  dir([pwd,app.default_options.Operator_Settings_fname(1:max(find(app.default_options.Operator_Settings_fname=='\'))),...
             '*_USER.dat' ]);
           
           File_Names = {temp_.name} ;
           
           [choice_, ok_]  = listdlg('ListString',File_Names,'SelectionMode','single') ;
          

           if ~isempty(choice_)
           
               if ~ok_;   msgbox('warning issue flagged with listchoice')  ;   end                  
           

           app.operator_settings   =  load_structure_from_file([pwd,app.default_options.Operator_Settings_fname(1:max(find(app.default_options.Operator_Settings_fname=='\'))),...
           File_Names{choice_}],app.default_options.Operator_expected_fields);
           app.operator_settings_table = create_table_from_structure(app.operator_settings);
           app.UITable.Data = app.operator_settings_table;
           app.ValueEditField.Value = num2str(getfield(app.operator_settings,app.FieldNameDropDown.Value));
           
           end %if ~isempty(choice_)
           

        end

        % Button pushed function: ExitandSetValuesButton
        function ExitandSetValuesButtonPushed(app, event)
            
            %set_operatorsettings(app.operator_settings)
            set_operatorsettings(app.parent_app ,app.operator_settings)
            delete(app)

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            %app.parent_app.OperatorsettingsButton.Enable = 'on' ;
            delete(app)

        end

        % Button pushed function: ExitwithoutvaluesButton
        function ExitwithoutvaluesButtonPushed(app, event)
           
            %app.parent_app.OperatorsettingsButton.Enable = 'on'
            app.parent_app.OperatorsettingsButton.Enable = 'on';
            %keyboard
            delete(app)

        end

        % Button pushed function: SetcurrentasdefaultButton
        function SetcurrentasdefaultButtonPushed(app, event)
            % Also
            % create a filename for old defaults
            % Old_OS#XXX#_USER.dat  - were XXX is a sequential number (starting at 1 etc)  
            % save old defaults with this name 
            % also save the old data
            

            [OK_ ] = save_current_OS_as_Default(app.saving_root, app.default_options,app.operator_settings);
            if ~ OK_     % if not OK
            msgbox('issue flagged saving data to structure')
            end % if ~Ok_
                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FieldNameDropDownLabel = uilabel(app.UIFigure);
            app.FieldNameDropDownLabel.Text = 'Field Name';
            app.FieldNameDropDown = uidropdown(app.UIFigure);
            app.FieldNameDropDown.ValueChangedFcn = createCallbackFcn(app, @FieldNameDropDownValueChanged, true);
            app.ValueEditFieldLabel = uilabel(app.UIFigure);
            app.ValueEditFieldLabel.Text = 'Value';
            app.ValueEditField = uieditfield(app.UIFigure, 'text');
            app.ValueEditField.ValueChangedFcn = createCallbackFcn(app, @ValueEditFieldValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UpdateValueButton = uibutton(app.UIFigure, 'push');
            app.UpdateValueButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateValueButtonPushed, true);
            app.UpdateValueButton.Text = 'Update Value';
            app.OpenFileButton = uibutton(app.UIFigure, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Text = 'Open File';
            app.SaveFileButton = uibutton(app.UIFigure, 'push');
            app.SaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFileButtonPushed, true);
            app.SaveFileButton.Text = 'Save File';
            app.OperatorSettingsLabel = uilabel(app.UIFigure);
            app.OperatorSettingsLabel.Text = 'Operator Settings';
            app.ReloadDefaultsButton = uibutton(app.UIFigure, 'push');
            app.ReloadDefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadDefaultsButtonPushed, true);
            app.ReloadDefaultsButton.Text = 'Reload Defaults';
            app.ExitandSetValuesButton = uibutton(app.UIFigure, 'push');
            app.ExitandSetValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ExitandSetValuesButtonPushed, true);
            app.ExitandSetValuesButton.Text = {'Exit and'; 'Set Values'};
            app.ExitwithoutvaluesButton = uibutton(app.UIFigure, 'push');
            app.ExitwithoutvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @ExitwithoutvaluesButtonPushed, true);
            app.ExitwithoutvaluesButton.Text = {'Exit without '; 'values'; ''};
            app.SetcurrentasdefaultButton = uibutton(app.UIFigure, 'push');
            app.SetcurrentasdefaultButton.ButtonPushedFcn = createCallbackFcn(app, @SetcurrentasdefaultButtonPushed, true);
            app.SetcurrentasdefaultButton.Text = {'Set current as'; 'default'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = operator_sub(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end