classdef set_snr < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UpdateValueButton        matlab.ui.control.Button
        FieldNameDropDown        matlab.ui.control.DropDown
        FieldNameDropDownLabel   matlab.ui.control.Label
        ValueEditField           matlab.ui.control.EditField
        ValueEditFieldLabel      matlab.ui.control.Label
        ReloadDefaultsButton     matlab.ui.control.Button
        ExitandsaveValuesButton  matlab.ui.control.Button
        ExitwithoutsavingButton  matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        SNROptionsLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        snr_settings          ;
        snr_settings_table    ;
        parent_app      ;
        default_options ;
        saving_root
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_ap, struct_1, struct_2)
            app.snr_settings       = struct_1   ;
            app.default_options = struct_2   ;
            app.parent_app = parent_ap;
            app.saving_root = getenv('APPDATA');

            app.FieldNameDropDown.Items = fieldnames(app.snr_settings)';

            app.snr_settings_table = create_table_from_structure(app.snr_settings);
            app.UITable.Data = app.snr_settings_table;
            app.UITable.ColumnName = app.snr_settings_table.Properties.VariableNames;


            app.ValueEditField.Value = num2str(getfield(app.snr_settings,app.FieldNameDropDown.Value));


        end

        % Button pushed function: ReloadDefaultsButton
        function ReloadDefaultsButtonPushed(app, event)
                       
           [app.snr_settings,ok_] = load_structure_from_file([pwd,app.default_options.snr_settings_fname],....
           app.default_options.snr_expected_fields);

           if ok_~= 1 ; msgbox('Warning:: structure fields dont match defaults') ;end      
           
           % now reset the display boxes and table
           app.snr_settings_table  = create_table_from_structure(app.snr_settings);
           app.UITable.Data = app.snr_settings_table;
           

        end

        % Button pushed function: ExitwithoutsavingButton
        function ExitwithoutsavingButtonPushed(app, event)
              delete(app)
        end

        % Button pushed function: ExitandsaveValuesButton
        function ExitandsaveValuesButtonPushed(app, event)
            
            saving_file_n_path =    [app.saving_root,app.default_options.snr_settings_fname];
            OK_ =  create_file_from_structure(app.snr_settings,saving_file_n_path);

            if ~OK_
            msgbox('file not saved (CHECK -- create_file_from_structure' )
            end
            
            % app.saving_root    
            
            set_snrsettings(app.parent_app ,app.snr_settings)
            delete(app)           
        end

        % Value changed function: FieldNameDropDown
        function FieldNameDropDownValueChanged(app, event)
            value = app.FieldNameDropDown.Value;
            app.ValueEditField.Value = num2str(getfield(app.snr_settings,app.FieldNameDropDown.Value));
        end

        % Button pushed function: UpdateValueButton
        function UpdateValueButtonPushed(app, event)
            
            if ~isempty(str2num(app.ValueEditField.Value))
            pass_val =  str2num(app.ValueEditField.Value); 
            % disp('here1')
            else
            pass_val = app.ValueEditField.Value;
            % disp('here2')
            end %if ~isempty(str2num(app.ValueEditField.Value))
            
            %[messge_ , check_ok] = Check_Proc_settings(app.FieldNameDropDown.Value,pass_val);
            %if check_ok ==1
            %app.operator_settings = setfield(app.operator_settings,num2str(app.FieldNameDropDown.Value), pass_val);
            %app.operator_settings = setfield(app.operator_settings,num2str(app.FieldNameDropDown.Value), app.ValueEditField.Value);
            
            app.snr_settings           = setfield(app.snr_settings,num2str(app.FieldNameDropDown.Value), pass_val);
            app.snr_settings_table     = create_table_from_structure(app.snr_settings);
            app.UITable.Data        = app.snr_settings_table;
            app.UITable.ColumnName  = app.snr_settings_table.Properties.VariableNames;
            
            %else
            %msgbox(messge_)    
            %end %if check_ok ==1

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SNROptionsLabel = uilabel(app.UIFigure);
            app.SNROptionsLabel.Text = 'SNR Options';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ExitwithoutsavingButton = uibutton(app.UIFigure, 'push');
            app.ExitwithoutsavingButton.ButtonPushedFcn = createCallbackFcn(app, @ExitwithoutsavingButtonPushed, true);
            app.ExitwithoutsavingButton.Text = 'Exit without saving';
            app.ExitandsaveValuesButton = uibutton(app.UIFigure, 'push');
            app.ExitandsaveValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ExitandsaveValuesButtonPushed, true);
            app.ExitandsaveValuesButton.Text = {'Exit and save'; 'Values'};
            app.ReloadDefaultsButton = uibutton(app.UIFigure, 'push');
            app.ReloadDefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadDefaultsButtonPushed, true);
            app.ReloadDefaultsButton.Text = 'Reload Defaults';
            app.ValueEditFieldLabel = uilabel(app.UIFigure);
            app.ValueEditFieldLabel.Text = 'Value';
            app.ValueEditField = uieditfield(app.UIFigure, 'text');
            app.FieldNameDropDownLabel = uilabel(app.UIFigure);
            app.FieldNameDropDownLabel.Text = 'Field Name';
            app.FieldNameDropDown = uidropdown(app.UIFigure);
            app.FieldNameDropDown.ValueChangedFcn = createCallbackFcn(app, @FieldNameDropDownValueChanged, true);
            app.UpdateValueButton = uibutton(app.UIFigure, 'push');
            app.UpdateValueButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateValueButtonPushed, true);
            app.UpdateValueButton.Text = 'Update Value';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = set_snr(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end