classdef test_set_sub < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ExitandsetValuesButton        matlab.ui.control.Button
        ExitwithoutvaluesButton       matlab.ui.control.Button
        DCRangeDropDown               matlab.ui.control.DropDown
        DCRangeLabel                  matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        TestSettingsLabel             matlab.ui.control.Label
        numberaveragesEditField       matlab.ui.control.EditField
        numberaveragesEditFieldLabel  matlab.ui.control.Label
        numbersamplesEditField        matlab.ui.control.EditField
        numbersamplesEditFieldLabel   matlab.ui.control.Label
        ReloadDefaultsButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
        parent_app                    ;   
        default_options               ;   
        test_settings                 ;
        test_settings_sampling_table  ;
        saving_directory              ;    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, par_, struct_1, struct_2)
            
            app.parent_app      = par_       ; 
            app.test_settings   = struct_1   ;
            app.default_options = struct_2   ;
            app.saving_directory =  [getenv('APPDATA'),'\SETTINGS_FILES'] ;
            cell_array_dc = arrayfun(@num2str,  app.test_settings.Sampling.adc_Range_options, 'UniformOutput', 0);
            app.DCRangeDropDown.Items =  cell_array_dc                                                           ; 
            app.DCRangeDropDown.Value =  cell_array_dc{app.test_settings.Sampling.adc_Range_}                    ;
            app.numbersamplesEditField.Value  =  num2str(app.test_settings.Sampling.Num_Samples)                 ;
            app.numberaveragesEditField.Value =  num2str(app.test_settings.Sampling.Num_Averages)                ; 
            app.test_settings_sampling_table  = create_table_from_structure(app.test_settings.Sampling);
            app.UITable.Data =  app.test_settings_sampling_table;
            app.UITable.ColumnName =  app.test_settings_sampling_table.Properties.VariableNames;
            

            %app.ValueEditField.Value = num2str(getfield(app.operator_settings,app.FieldNameDropDown.Value));
 
        end

        % Value changed function: DCRangeDropDown
        function DCRangeDropDownValueChanged(app, event)
            value = app.DCRangeDropDown.Value;
         app.test_settings.Sampling.adc_Range_     = find(app.test_settings.Sampling.adc_Range_options== str2num(value));
         
         app.test_settings_sampling_table  = create_table_from_structure(app.test_settings.Sampling);
         app.UITable.Data =  app.test_settings_sampling_table;
         app.UITable.ColumnName =  app.test_settings_sampling_table.Properties.VariableNames;
   

        end

        % Value changed function: numbersamplesEditField
        function numbersamplesEditFieldValueChanged(app, event)
            value = app.numbersamplesEditField.Value;
         
         app.test_settings.Sampling.Num_Samples    = str2num(value);
         
         app.test_settings_sampling_table  = create_table_from_structure(app.test_settings.Sampling);
         app.UITable.Data =  app.test_settings_sampling_table;
         app.UITable.ColumnName =  app.test_settings_sampling_table.Properties.VariableNames;
   

        end

        % Value changed function: numberaveragesEditField
        function numberaveragesEditFieldValueChanged(app, event)
            value = app.numberaveragesEditField.Value;
           app.test_settings.Sampling.Num_Averages   = str2num(value);
         app.test_settings_sampling_table  = create_table_from_structure(app.test_settings.Sampling);
         app.UITable.Data =  app.test_settings_sampling_table;
         app.UITable.ColumnName =  app.test_settings_sampling_table.Properties.VariableNames;
   

        end

        % Callback function: not associated with a component
        function UpdateValuesButtonPushed(app, event)
        % not sure How to delete

        end

        % Button pushed function: ReloadDefaultsButton
        function ReloadDefaultsButtonPushed(app, event)
            [app.test_settings ,ok_] = load_structure_from_file([pwd,app.default_options.Test_Settings_fname],....
            app.default_options.Test_expected_fields);
           
            if ok_~= 1 ; msgbox('Warning:: structure fields dont match defaults') ;end      
           % now reset the display boxes and table
            app.test_settings_sampling_table  = create_table_from_structure(app.test_settings.Sampling);
            app.UITable.Data =  app.test_settings_sampling_table;
            app.UITable.ColumnName =  app.test_settings_sampling_table.Properties.VariableNames;
          
        end

        % Button pushed function: ExitwithoutvaluesButton
        function ExitwithoutvaluesButtonPushed(app, event)
            app.parent_app.TestSettingsButton.Enable = 'on';
            delete(app)
  
        end

        % Button pushed function: ExitandsetValuesButton
        function ExitandsetValuesButtonPushed(app, event)
            set_testsettings(app.parent_app,app.test_settings)

            % getenv('APPDATA')
            saving_file_n_path = [getenv('APPDATA'),app.default_options.Test_Settings_fname];
                        
            OK_ =  create_file_from_structure(app.test_settings,saving_file_n_path);

            if ~OK_
            msgbox('file not saved (CHECK -- create_file_from_structure' )
            end

            
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReloadDefaultsButton = uibutton(app.UIFigure, 'push');
            app.ReloadDefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadDefaultsButtonPushed, true);
            app.ReloadDefaultsButton.Text = 'Reload Defaults';
            app.numbersamplesEditFieldLabel = uilabel(app.UIFigure);
            app.numbersamplesEditFieldLabel.Text = 'number samples';
            app.numbersamplesEditField = uieditfield(app.UIFigure, 'text');
            app.numbersamplesEditField.InputType = 'digits';
            app.numbersamplesEditField.ValueChangedFcn = createCallbackFcn(app, @numbersamplesEditFieldValueChanged, true);
            app.numberaveragesEditFieldLabel = uilabel(app.UIFigure);
            app.numberaveragesEditFieldLabel.Text = 'number averages';
            app.numberaveragesEditField = uieditfield(app.UIFigure, 'text');
            app.numberaveragesEditField.InputType = 'digits';
            app.numberaveragesEditField.ValueChangedFcn = createCallbackFcn(app, @numberaveragesEditFieldValueChanged, true);
            app.TestSettingsLabel = uilabel(app.UIFigure);
            app.TestSettingsLabel.Text = 'Test Settings';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.DCRangeLabel = uilabel(app.UIFigure);
            app.DCRangeLabel.Text = 'DC Range    ';
            app.DCRangeDropDown = uidropdown(app.UIFigure);
            app.DCRangeDropDown.ValueChangedFcn = createCallbackFcn(app, @DCRangeDropDownValueChanged, true);
            app.ExitwithoutvaluesButton = uibutton(app.UIFigure, 'push');
            app.ExitwithoutvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @ExitwithoutvaluesButtonPushed, true);
            app.ExitwithoutvaluesButton.Text = 'Exit without values';
            app.ExitandsetValuesButton = uibutton(app.UIFigure, 'push');
            app.ExitandsetValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ExitandsetValuesButtonPushed, true);
            app.ExitandsetValuesButton.Text = {'Exit and set'; 'Values'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test_set_sub(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end