classdef view_snr < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        CloseButton      matlab.ui.control.Button
        UITable          matlab.ui.control.Table
        SNROptionsLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        snr_pars          ;
        snr_pars_table    ;

        parent_ap_han      ;    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_ap, struct_1)
            app.snr_pars = struct_1;
            app.parent_ap_han = parent_ap;
            app.snr_pars_table = create_table_from_structure(app.snr_pars);
            
            app.UITable.Data = app.snr_pars_table;
            app.UITable.ColumnName = app.snr_pars_table.Properties.VariableNames;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SNROptionsLabel = uilabel(app.UIFigure);
            app.SNROptionsLabel.Text = 'SNR Options';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = view_snr(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end