classdef view_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        CloseButton        matlab.ui.control.Button
        ViewDropDown       matlab.ui.control.DropDown
        ViewLabel          matlab.ui.control.Label
        UITable            matlab.ui.control.Table
        TestSettingsLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        test_settings          ;
        test_settings_sampling_table; 
        test_settings_Impedence_table;
        test_settings_Toneburst_table;
        test_settings_Instrument_table;
        test_settings_Model_table;
        parent_ap_han          ;

        % Description
    end
    
    methods (Access = private)
        
        
function  Update_Table(app)
     
switch(app.ViewDropDown.Value)

    case('Test_samples')
app.UITable.Data           =       app.test_settings_sampling_table;      
app.UITable.ColumnName     =       app.test_settings_sampling_table.Properties.VariableNames;
app.UITable.FontSize       =       12;

case('Test_Impedence')
app.UITable.Data           =       app.test_settings_Impedence_table;      
app.UITable.ColumnName     =       app.test_settings_Impedence_table.Properties.VariableNames;
app.UITable.FontSize       =       12;
    
case('Test_Toneburst')    
app.UITable.Data           =       app.test_settings_Toneburst_table;      
app.UITable.ColumnName     =       app.test_settings_Toneburst_table     .Properties.VariableNames;
app.UITable.FontSize       =       12;
    
case('Test_Instrument')
app.UITable.Data           =       app.test_settings_Instrument_table;      
app.UITable.ColumnName     =       app.test_settings_Instrument_table.Properties.VariableNames;
app.UITable.FontSize       =       12;
    
case('Test_Model')    
app.UITable.Data           =       app.test_settings_Model_table;      
app.UITable.ColumnName     =       app.test_settings_Model_table.Properties.VariableNames;
app.UITable.FontSize       =       12;

end %switch(app.VIEWDropDown.Value)


 end   
 end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_ap, struct_1)
            app.test_settings = struct_1;
            app.parent_ap_han = parent_ap;

app.test_settings_sampling_table     = create_table_from_structure(app.test_settings.Sampling);
app.test_settings_Impedence_table    = create_table_from_structure(app.test_settings.Impedence);  
app.test_settings_Toneburst_table    = create_table_from_structure(app.test_settings.Toneburst);
app.test_settings_Instrument_table   = create_table_from_structure(app.test_settings.Instrument_attributes);
app.test_settings_Model_table        = create_table_from_structure(app.test_settings.Model_data); 

app.UITable.Data           =       app.test_settings_sampling_table;      
app.UITable.ColumnName     =       app.test_settings_sampling_table.Properties.VariableNames;
app.UITable.FontSize       =       12;

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
              delete(app)
        end

        % Value changed function: ViewDropDown
        function ViewDropDownValueChanged(app, event)
            value = app.ViewDropDown.Value;
             app.Update_Table();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TestSettingsLabel = uilabel(app.UIFigure);
            app.TestSettingsLabel.Text = 'Test Settings';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ViewLabel = uilabel(app.UIFigure);
            app.ViewLabel.Text = 'View :';
            app.ViewDropDown = uidropdown(app.UIFigure);
            app.ViewDropDown.Items = {'Test_samples', 'Test_Impedence', 'Test_Toneburst', 'Test_Instrument', 'Test_Model'};
            app.ViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewDropDownValueChanged, true);
            app.ViewDropDown.Value = 'Test_samples';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = view_test(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end